classdef Analysis_Final_File < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout2                    matlab.ui.container.GridLayout
        StatueLamp                     matlab.ui.control.Lamp
        StatueLampLabel                matlab.ui.control.Label
        ExcludedfrequencypointsTextArea  matlab.ui.control.TextArea
        ExcludedfrequencypointsTextAreaLabel  matlab.ui.control.Label
        AnalysisResultsEditField       matlab.ui.control.EditField
        AnalysisResultsEditFieldLabel  matlab.ui.control.Label
        TidefileEditField              matlab.ui.control.EditField
        TidefileEditFieldLabel         matlab.ui.control.Label
        CombinationPanel               matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        BsplineCheckBox                matlab.ui.control.CheckBox
        RobustregressionstrategyCheckBox  matlab.ui.control.CheckBox
        CorrelationscatterCheckBox     matlab.ui.control.CheckBox
        chooseButton_3                 matlab.ui.control.StateButton
        FilesinfomationPanel           matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        AntennaheightmEditField        matlab.ui.control.NumericEditField
        AntennaheightmEditFieldLabel   matlab.ui.control.Label
        FinalFileListTextArea          matlab.ui.control.TextArea
        FinalFileListTextAreaLabel     matlab.ui.control.Label
        FilepathEditField              matlab.ui.control.EditField
        FilepathEditFieldLabel         matlab.ui.control.Label
        FilenumberEditField            matlab.ui.control.NumericEditField
        FilenumberEditFieldLabel       matlab.ui.control.Label
        StationnameEditField           matlab.ui.control.EditField
        StationnameEditFieldLabel      matlab.ui.control.Label
        chooseButton                   matlab.ui.control.Button
        chooseButton_2                 matlab.ui.control.Button
        AnalysisButton                 matlab.ui.control.Button
        AnalysisFinalFileLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chooseButton
        function chooseButtonPushed(app, event)
            [fileNames, pathName] = uigetfile('results/*.*', 'choose final files', 'MultiSelect', 'on');
            if ischar(fileNames)
                fileNames = {fileNames};
            end
            app.FinalFileListTextArea.Value = cellstr(fileNames);
            app.FilepathEditField.Value = pathName;

            % display number
            app.FilenumberEditField.Value = length(fileNames);

            names = cellfun(@(x) strsplit(x, '_'), fileNames, 'UniformOutput', false);
            names = cellfun(@(y) y{1}, names, 'UniformOutput', false);
            if ~all(strcmp(names{1}, names))
                fig = app.UIFigure;
                uialert(fig,'Please choose the files in the same station!')
                return
            else
                app.StationnameEditField.Value = names{1};
            end

        end

        % Button pushed function: chooseButton_2
        function chooseButton_2Pushed(app, event)
            [fileNames, pathName] = uigetfile('data/tide_data/*.*', 'choose final files');
            app.TidefileEditField.Value = fullfile(pathName,fileNames);
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            try
                app.StatueLamp.Color = 'g';
                drawnow
                Analysis_Callback(app)
            catch ME
                app.StatueLamp.Color = 'r';
                fig = app.UIFigure;
                uialert(fig,[ME.message,newline,'File:',ME.stack(1,:).file,newline,'Name:',...
                    ME.stack(1,:).name,newline,'Line:',num2str(ME.stack(1,:).line)],'warning')
                return
            end
        end

        % Value changed function: chooseButton_3
        function chooseButton_3ValueChanged(app, event)
            dname = uigetdir('results\','choose the output dir');
            app.AnalysisResultsEditField.Value = dname;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.AnalysisFinalFileLabel = uilabel(app.GridLayout2);
            app.AnalysisFinalFileLabel.Text = 'Analysis Final File';
            app.AnalysisButton = uibutton(app.GridLayout2, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Icon = fullfile(pathToMLAPP, 'icon', '数据可视化.png');
            app.AnalysisButton.Text = 'Analysis';
            app.chooseButton_2 = uibutton(app.GridLayout2, 'push');
            app.chooseButton_2.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_2Pushed, true);
            app.chooseButton_2.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_2.Text = 'choose';
            app.FilesinfomationPanel = uipanel(app.GridLayout2);
            app.FilesinfomationPanel.Title = 'Files infomation';
            app.GridLayout = uigridlayout(app.FilesinfomationPanel);
            app.chooseButton = uibutton(app.GridLayout, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.chooseButton.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton.Text = 'choose';
            app.StationnameEditFieldLabel = uilabel(app.GridLayout);
            app.StationnameEditFieldLabel.Text = 'Station name';
            app.StationnameEditField = uieditfield(app.GridLayout, 'text');
            app.FilenumberEditFieldLabel = uilabel(app.GridLayout);
            app.FilenumberEditFieldLabel.Text = 'File number';
            app.FilenumberEditField = uieditfield(app.GridLayout, 'numeric');
            app.FilepathEditFieldLabel = uilabel(app.GridLayout);
            app.FilepathEditFieldLabel.Text = 'File path';
            app.FilepathEditField = uieditfield(app.GridLayout, 'text');
            app.FinalFileListTextAreaLabel = uilabel(app.GridLayout);
            app.FinalFileListTextAreaLabel.Text = 'Final File List';
            app.FinalFileListTextArea = uitextarea(app.GridLayout);
            app.AntennaheightmEditFieldLabel = uilabel(app.GridLayout);
            app.AntennaheightmEditFieldLabel.Text = 'Antenna height(m)';
            app.AntennaheightmEditField = uieditfield(app.GridLayout, 'numeric');
            app.AntennaheightmEditField.Value = 12;
            app.chooseButton_3 = uibutton(app.GridLayout2, 'state');
            app.chooseButton_3.ValueChangedFcn = createCallbackFcn(app, @chooseButton_3ValueChanged, true);
            app.chooseButton_3.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_3.Text = 'choose';
            app.CorrelationscatterCheckBox = uicheckbox(app.GridLayout2);
            app.CorrelationscatterCheckBox.Text = 'Correlation scatter';
            app.CombinationPanel = uipanel(app.GridLayout2);
            app.CombinationPanel.Title = 'Combination';
            app.GridLayout3 = uigridlayout(app.CombinationPanel);
            app.RobustregressionstrategyCheckBox = uicheckbox(app.GridLayout3);
            app.RobustregressionstrategyCheckBox.Text = 'Robust regression strategy';
            app.BsplineCheckBox = uicheckbox(app.GridLayout3);
            app.BsplineCheckBox.Text = 'B-spline';
            app.TidefileEditFieldLabel = uilabel(app.GridLayout2);
            app.TidefileEditFieldLabel.Text = 'Tide file';
            app.TidefileEditField = uieditfield(app.GridLayout2, 'text');
            app.AnalysisResultsEditFieldLabel = uilabel(app.GridLayout2);
            app.AnalysisResultsEditFieldLabel.Text = 'Analysis Results';
            app.AnalysisResultsEditField = uieditfield(app.GridLayout2, 'text');
            app.ExcludedfrequencypointsTextAreaLabel = uilabel(app.GridLayout2);
            app.ExcludedfrequencypointsTextAreaLabel.Text = 'Excluded frequency points';
            app.ExcludedfrequencypointsTextArea = uitextarea(app.GridLayout2);
            app.StatueLampLabel = uilabel(app.GridLayout2);
            app.StatueLampLabel.Text = 'Statue';
            app.StatueLamp = uilamp(app.GridLayout2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_Final_File

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end