classdef SNR_Inverse_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout22                    matlab.ui.container.GridLayout
        InversemodelinginversionLabel   matlab.ui.control.Label
        TabGroup10                      matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        GridLayout13_2                  matlab.ui.container.GridLayout
        TabGroup7                       matlab.ui.container.TabGroup
        StationTab                      matlab.ui.container.Tab
        GridLayout14_2                  matlab.ui.container.GridLayout
        stationmap                      matlab.ui.container.Panel
        StationinformationPanel         matlab.ui.container.Panel
        GridLayout15_2                  matlab.ui.container.GridLayout
        ImportSettingsButton            matlab.ui.control.StateButton
        belong                          matlab.ui.control.EditField
        Label_50                        matlab.ui.control.Label
        station                         matlab.ui.control.EditField
        Label_49                        matlab.ui.control.Label
        Image8                          matlab.ui.control.Image
        SettingsofelevationandazimuthPanel  matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        EditField_3Label_2              matlab.ui.control.Label
        azimask_max                     matlab.ui.control.EditField
        azimask_min                     matlab.ui.control.EditField
        Label_51                        matlab.ui.control.Label
        azi_max                         matlab.ui.control.EditField
        EditField_3Label                matlab.ui.control.Label
        azi_min                         matlab.ui.control.EditField
        AzimuthminLabel                 matlab.ui.control.Label
        elv_max                         matlab.ui.control.EditField
        maxLabel                        matlab.ui.control.Label
        elv_min                         matlab.ui.control.EditField
        ElevationminLabel               matlab.ui.control.Label
        LocationinfoPanel               matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        basemap                         matlab.ui.control.DropDown
        DropDown_2Label_9               matlab.ui.control.Label
        HEditField                      matlab.ui.control.EditField
        HEditFieldLabel                 matlab.ui.control.Label
        load_station_info               matlab.ui.control.Button
        SpacerectangularcoordinatesystemmLabel  matlab.ui.control.Label
        GeodeticcoordinatesystemmLabel  matlab.ui.control.Label
        get_station_info                matlab.ui.control.Button
        lat                             matlab.ui.control.EditField
        LatLabel                        matlab.ui.control.Label
        lon                             matlab.ui.control.EditField
        LonLabel                        matlab.ui.control.Label
        Z                               matlab.ui.control.NumericEditField
        mZLabel                         matlab.ui.control.Label
        Y                               matlab.ui.control.NumericEditField
        mYLabel                         matlab.ui.control.Label
        X                               matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        FilesTab                        matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        SaveLoadSettingsButton          matlab.ui.control.Button
        Image9                          matlab.ui.control.Image
        TimsPanel                       matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        endtime                         matlab.ui.control.DatePicker
        DatePicker_2Label               matlab.ui.control.Label
        starttime                       matlab.ui.control.DatePicker
        StartdateLabel                  matlab.ui.control.Label
        EphemerisFilesPanel             matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        sp3filename                     matlab.ui.control.TextArea
        Label_55                        matlab.ui.control.Label
        sp3_type                        matlab.ui.control.DropDown
        Label_54                        matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        sp3filenumber                   matlab.ui.control.Label
        path_sp3                        matlab.ui.control.Button
        sp3                             matlab.ui.control.EditField
        Pathofsp3Label                  matlab.ui.control.Label
        getsp3                          matlab.ui.control.Button
        RINEXFilesPanel                 matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        dt                              matlab.ui.control.EditField
        Label_53                        matlab.ui.control.Label
        obsfilename                     matlab.ui.control.TextArea
        Label_52                        matlab.ui.control.Label
        rinex_version                   matlab.ui.control.DropDown
        rinexversionLabel               matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        obsfilenumber                   matlab.ui.control.Label
        path_o                          matlab.ui.control.Button
        observation                     matlab.ui.control.EditField
        PathofrinexLabel                matlab.ui.control.Label
        SNRextractionTab                matlab.ui.container.Tab
        GridLayout2_2                   matlab.ui.container.GridLayout
        TabGroup8_2                     matlab.ui.container.TabGroup
        SNRTab_4                        matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        TabGroup2_2                     matlab.ui.container.TabGroup
        GPSTab_2                        matlab.ui.container.Tab
        GridLayout4_2                   matlab.ui.container.GridLayout
        GPS_band                        matlab.ui.control.DropDown
        Label_23                        matlab.ui.control.Label
        GPS_satellite_display           matlab.ui.control.DropDown
        DropDown_2Label_5               matlab.ui.control.Label
        Image7_5                        matlab.ui.control.Image
        Image6_5                        matlab.ui.control.Image
        Image5_5                        matlab.ui.control.Image
        GPS_arc                         matlab.ui.control.DropDown
        ArcLabel                        matlab.ui.control.Label
        GPS_result                      matlab.ui.control.Table
        GPS_SNR                         matlab.ui.control.UIAxes
        GLONASSTab_2                    matlab.ui.container.Tab
        GridLayout5_2                   matlab.ui.container.GridLayout
        GLONASS_arc                     matlab.ui.control.DropDown
        Label_25                        matlab.ui.control.Label
        GLONASS_band                    matlab.ui.control.DropDown
        Label_24                        matlab.ui.control.Label
        GLONASS_satellite_display       matlab.ui.control.DropDown
        DropDown_2Label_6               matlab.ui.control.Label
        Image7_6                        matlab.ui.control.Image
        Image5_6                        matlab.ui.control.Image
        Image6_6                        matlab.ui.control.Image
        GLONASS_result                  matlab.ui.control.Table
        GLONASS_SNR                     matlab.ui.control.UIAxes
        GALILEOTab_2                    matlab.ui.container.Tab
        GridLayout6_2                   matlab.ui.container.GridLayout
        GALILEO_arc                     matlab.ui.control.DropDown
        Label_27                        matlab.ui.control.Label
        GALILEO_band                    matlab.ui.control.DropDown
        Label_26                        matlab.ui.control.Label
        GALILEO_satellite_display       matlab.ui.control.DropDown
        DropDown_2Label_7               matlab.ui.control.Label
        Image7_7                        matlab.ui.control.Image
        Image5_7                        matlab.ui.control.Image
        Image6_7                        matlab.ui.control.Image
        GALILEO_result                  matlab.ui.control.Table
        GALILEO_SNR                     matlab.ui.control.UIAxes
        BDSTab_2                        matlab.ui.container.Tab
        GridLayout7_2                   matlab.ui.container.GridLayout
        BDS_arc                         matlab.ui.control.DropDown
        Label_29                        matlab.ui.control.Label
        BDS_band                        matlab.ui.control.DropDown
        Label_28                        matlab.ui.control.Label
        BDS_satellite_display           matlab.ui.control.DropDown
        DropDown_2Label_8               matlab.ui.control.Label
        Image7_8                        matlab.ui.control.Image
        Image5_8                        matlab.ui.control.Image
        Image6_8                        matlab.ui.control.Image
        BDS_result                      matlab.ui.control.Table
        BDS_SNR                         matlab.ui.control.UIAxes
        SkymapTab                       matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        satellite_sky                   matlab.ui.control.ListBox
        Label_30                        matlab.ui.control.Label
        GNSS_sky                        matlab.ui.control.ListBox
        GNSSLabel                       matlab.ui.control.Label
        skyview_2                       matlab.ui.container.TabGroup
        sky_all                         matlab.ui.container.Tab
        sky_selected                    matlab.ui.container.Tab
        Image4_2                        matlab.ui.control.Image
        display_date                    matlab.ui.control.DropDown
        Label_21                        matlab.ui.control.Label
        path_SNR                        matlab.ui.control.EditField
        ResultpathLabel                 matlab.ui.control.Label
        chooseButton_2                  matlab.ui.control.Button
        ExtractSNRdataButton            matlab.ui.control.Button
        InversemodelingTab              matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        plotmodsnr                      matlab.ui.control.Switch
        Label_46                        matlab.ui.control.Label
        RunningstateLamp                matlab.ui.control.Lamp
        RunningstateLampLabel           matlab.ui.control.Label
        inverse_model                   matlab.ui.control.StateButton
        ModelingoptionsPanel            matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        rough_sw                        matlab.ui.control.Switch
        Label_3                         matlab.ui.control.Label
        ahgt_bounds                     matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        ahgt                            matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        kspac_hours                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        tlen_hours                      matlab.ui.control.NumericEditField
        EditField_5Label_2              matlab.ui.control.Label
        roughness_adjustment            matlab.ui.control.EditField
        ResultsLabel                    matlab.ui.control.Label
        roughness                       matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        SavePathLabel                   matlab.ui.control.Label
        EditField_2Label                matlab.ui.control.Label
        inverse_analysis_result_path    matlab.ui.control.EditField
        chose_path_for_inversse         matlab.ui.control.Button
        GNSSPanel                       matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        BDSCheckBox                     matlab.ui.control.CheckBox
        GALILEOCheckBox                 matlab.ui.control.CheckBox
        GLONASSCheckBox                 matlab.ui.control.CheckBox
        GPSCheckBox                     matlab.ui.control.CheckBox
        path_SNR_data                   matlab.ui.control.EditField
        chooseButton_3                  matlab.ui.control.Button
        SNR_model                       matlab.ui.control.UIAxes
        TidallevelverificationTab       matlab.ui.container.Tab
        GridLayout23                    matlab.ui.container.GridLayout
        chooseButton_4                  matlab.ui.control.Button
        point_day                       matlab.ui.control.NumericEditField
        Label_56                        matlab.ui.control.Label
        FinalfilepathEditField          matlab.ui.control.EditField
        FinalfilepathEditFieldLabel     matlab.ui.control.Label
        result_scatter                  matlab.ui.control.StateButton
        DrawingoptionsLabel             matlab.ui.control.Label
        DH                              matlab.ui.control.CheckBox
        cor                             matlab.ui.control.CheckBox
        rms_sp                          matlab.ui.control.NumericEditField
        rmscmLabel                      matlab.ui.control.Label
        rms_inv                         matlab.ui.control.NumericEditField
        InverseModelingrmscmLabel       matlab.ui.control.Label
        tide_path                       matlab.ui.control.EditField
        Label_5                         matlab.ui.control.Label
        chooseButton                    matlab.ui.control.Button
        inverse_results                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Button_3Pushed(app, event)
            if exist("Exchange_information\station_setting.mat","file")

                global time sp3_type rinex_version station_l station_xyz elv azimask azi station_belong station_name sp3_path obs_path dt
                load("Exchange_information\station_setting.mat")

                fig = app.UIFigure;
                uialert(fig,'测站信息加载成功','success','Icon','success');
            else
                fig = app.UIFigure;
                uialert(fig,'请先设置测站信息！','warning');
            end
        end

        % Button pushed function: chooseButton_3
        function chooseButton_3Pushed(app, event)
            dname = uigetdir(pwd);
            app.path_SNR_data.Value = dname;
            return
        end

        % Button pushed function: chose_path_for_inversse
        function chose_path_for_inversseButtonPushed(app, event)
            dname = uigetdir(pwd);
            app.inverse_analysis_result_path.Value = dname;
            return
        end

        % Value changed function: inverse_model
        function inverse_modelValueChanged(app, event)
            try
                
                app.RunningstateLamp.Color = 'g';
                drawnow
                global Operation_settings

                startdate = datenum(Operation_settings.time(1));
                enddate = datenum(Operation_settings.time(2));

                snrdir = app.path_SNR_data.Value;
                outdir = app.inverse_analysis_result_path.Value;

                kspac = app.kspac_hours.Value/24; % in days
                tlen = app.tlen_hours.Value/24; % in days
                decimate = str2double(Operation_settings.dt); % in seconds
                gps=0;  glo=0;  gal=0;  bds=0;
                if app.GPSCheckBox.Value == 1
                    gps = 1;
                end
                if app.GLONASSCheckBox.Value == 1
                    glo = 1;
                end
                if app.GALILEOCheckBox.Value == 1
                    gal = 1;
                end
                if app.BDSCheckBox.Value == 1
                    bds =1;
                end
                satconsts = [gps,glo,gal,bds];

                roughin = str2double(app.roughness.Value);
                largetides = 1;
                skipjs = 0;

                tdatenum = startdate - tlen/3;
                while round(tdatenum, 10, 'significant') < round(enddate+1 - tlen/3, 10, 'significant')
                    tdatenum = tdatenum + tlen/3;

                    [sfacsjs,sfacspre,hinit,xinit,consts_out,roughout] = inversemodel(app, tdatenum,Operation_settings.station_name, ...
                        snrdir,kspac,tlen,decimate,satconsts,largetides,roughin,skipjs);
                    if exist(outdir)==0 % create output dir
                        mkdir(outdir);
                    end
                    % saving results
                    save([outdir,'/',num2str(round(tdatenum,10,'significant')),'.mat'],'sfacsjs','sfacspre','hinit','xinit','consts_out','roughout')

                end
                app.RunningstateLamp.Color = 'r';
                fig = app.UIFigure;
                uialert(fig,'Modeling Complete!','success','Icon','success')
            catch ME
                drawnow
                app.RunningstateLamp.Color = 'r';
                fig = app.UIFigure;
                uialert(fig,[ME.message,newline,'File:',ME.stack(1,:).file,newline,'Name:',...
                    ME.stack(1,:).name,newline,'Line:',num2str(ME.stack(1,:).line)],'warning')
                return

            end
        end

        % Button pushed function: chooseButton
        function chooseButtonPushed(app, event)
            [fileNames, filepath] = uigetfile(strcat(pwd,'/tide_data/*.*'), 'choose tide file');
            app.tide_path.Value = strcat(filepath,'/',fileNames);
            return
        end

        % Value changed function: result_scatter
        function result_scatterValueChanged(app, event)
            
            % path setting
            invdir = cellstr({app.inverse_analysis_result_path.Value});
            tgstring = app.tide_path.Value;

            kspac = app.kspac_hours.Value/24; % in days
            tlen = app.tlen_hours.Value/24; % in days
            plotl = 1/(24*60);
            roughnessplot = 0;
            Final_file_path = app.FinalfilepathEditField.Value;
            [t_rh,rh_invjs,rh_invpre,rms_js,rms_pre] = invsnr_plot(app,invdir,kspac,tlen,plotl,tgstring,roughnessplot,Final_file_path);

            app.rms_inv.Value = rms_js*100;
            app.rms_sp.Value = rms_pre*100;
        end

        % Callback function
        function load_station_infoButtonPushed(app, event)
            [fileNames, filepath] = uigetfile(strcat(pwd,'\data\station_info\*.m*'),'Select station information');
            run(strcat(filepath,'/',fileNames))
            app.elv_max.Value = num2str(elv_lim(2));
            app.elv_min.Value = num2str(elv_lim(1));
            app.azi_min.Value = num2str(azi_lim(1));
            app.azi_max.Value = num2str(azi_lim(2));
            app.azimask_max.Value = num2str(azi_mask(2));
            app.azimask_min.Value = num2str(azi_mask(1));
            app.lat.Value = num2str(sat_lat);
            app.lon.Value = num2str(sat_lon);

            app.X.Value = staxyz(1);
            app.Y.Value = staxyz(2);
            app.Z.Value = staxyz(3);
        end

        % Callback function
        function get_station_infoButtonPushed(app, event)
            addpath("part1\")
            fig = app.UIFigure;
            uiprogressdlg(fig,'Message','Waiting……','Cancelable','on');

            % station map
            plon = str2double(app.lon.Value);
            plat = str2double(app.lat.Value);

            h = geoaxes(app.stationmap);
            geoscatter(h, plat, plon, 'filled', 'red');
            geolimits(h,[plat-0.0025 plat+0.0025],[plon-0.00125 plon+0.00125]);
            geobasemap(h,app.basemap.Value)
            legend(h,'station')
        end

        % Callback function
        function path_oButtonPushed(app, event)
            [fileNames, filepath] = uigetfile('data/*.*', '选择观测文件', 'MultiSelect', 'on');

            if isequal(fileNames, 0)
                return;
            end
            fileNames = cellstr(fileNames);
            fig = app.UIFigure;

            fileCount = 0;
            fileCount = fileCount + length(fileNames);
            app.obsfilenumber.Text = sprintf('已选择：%d个文件', fileCount);
            app.observation.Value = filepath;
            date_is = 1;
            if fileCount == 1
                % filenames
                app.obsfilename.Value = fileNames;
                % rinex version
                fileNames_char = cell2mat(fileNames);
                fileID = fopen(strcat(filepath,'\',fileNames_char), 'r');
                data = textscan(fileID, '%s', 'Delimiter', '\n');
                fclose(fileID);
                for i = 1:length(data{1})
                    line = data{1}{i};
                    if endsWith(line, 'RINEX VERSION / TYPE')
                        ver = line(1:9);
                    end
                end
                if str2double(ver) >= 3
                    app.rinex_version.Value = 'rinex 3';
                elseif str2double(ver) >= 2 && str2double(ver) < 3
                    app.rinex_version.Value = 'rinex 2';
                else
                    uialert(fig,{'无法自动读取rinex版本','请手动选择版本与日期！'},'Warning','Icon','warning');
                    date_is = 0;
                end
                % datetime
                if date_is == 1
                    if app.rinex_version.Value == 'rinex 3'
                        doy_str = fileNames_char(17:19);
                        year_str = fileNames_char(13:16);
                        start_date = datetime(str2double(year_str),1,str2double(doy_str));
                        end_date = start_date;
                    elseif app.rinex_version.Value == 'rinex 2'
                        doy_str = fileNames_char(5:7);
                        year_str = strcat('20',fileNames_char(10:11));
                        start_date = datetime(str2double(year_str),1,str2double(doy_str));
                        end_date = start_date;
                    end
                    app.starttime.Value = start_date;
                    app.endtime.Value = end_date;
                end
            else % multiple files
                app. obsfilename.Value = fileNames';
                % rinex version
                fileNames_char = cell2mat(fileNames(1));
                fileID = fopen(strcat(filepath,'\',fileNames_char), 'r');
                data = textscan(fileID, '%s', 'Delimiter', '\n');
                fclose(fileID);
                for i = 1:length(data{1})
                    line = data{1}{i};
                    if endsWith(line, 'RINEX VERSION / TYPE')
                        ver = line(1:9);
                    end
                end
                if str2double(ver) >= 3
                    app.rinex_version.Value = 'rinex 3';
                elseif str2double(ver) >= 2 && str2double(ver) < 3
                    app.rinex_version.Value = 'rinex 2';
                else
                    uialert(fig,{'无法自动读取rinex版本','请手动选择版本与日期！'},'Warning','Icon','warning');
                    date_is = 0;
                end
                % datetime
                if date_is == 1
                    fileNames_char = cell2mat(fileNames');
                    for days = 1:fileCount
                        if app.rinex_version.Value == 'rinex 3'
                            fileNames_day = fileNames_char(days,:);
                            doy_str = fileNames_day(17:19);
                            year_str = fileNames_day(13:16);
                            date(days) = datetime(str2double(year_str),1,str2double(doy_str));
                        elseif app.rinex_version.Value == 'rinex 2'
                            fileNames_day = fileNames_char(days,:);
                            doy_str = fileNames_day(5:7);
                            year_str = strcat('20',fileNames_day(10:11));
                            date(days) = datetime(str2double(year_str),1,str2double(doy_str));

                        end
                    end
                    date = sort(date,'ascend');
                    start_date = date(1);
                    end_date = date(end);
                    app.starttime.Value = start_date;
                    app.endtime.Value = end_date;
                end
            end
        end

        % Callback function
        function path_sp3ButtonPushed(app, event)
            [fileNames, filepath] = uigetfile('data/sp3/*.*', '选择星历文件', 'MultiSelect', 'on');

            if isequal(fileNames, 0)
                return;
            end

            fileNames = cellstr(fileNames);
            fileCount = 0;
            fileCount = fileCount + length(fileNames);
            app.sp3filenumber.Text = sprintf('已选择：%d个文件', fileCount);
            app.sp3.Value = filepath;
            if fileCount == 1
                app.sp3filename.Value = fileNames;

                filenames_char = cell2mat(fileNames);
                app.sp3_type.Value = filenames_char(1:3);
            else
                app. sp3filename.Value = fileNames';
                filenames_char = cell2mat(fileNames(1));
                app.sp3_type.Value = filenames_char(1:3);
            end
        end

        % Callback function
        function getsp3ButtonPushed(app, event)
            addpath('part1\')
            start_date = app.starttime.Value;
            end_date = app.endtime.Value;
            sp3_option = app.sp3_type.Value;
            save_path_sp3 = app.sp3.Value;
            get_sp3(app,start_date,end_date,sp3_option,save_path_sp3)
        end

        % Callback function
        function Button_8Pushed(app, event)
         
        end

        % Button pushed function: chooseButton_2
        function chooseButton_2Pushed(app, event)
            dname = uigetdir(pwd);
            app.path_SNR.Value = dname;
            return
        end

        % Button pushed function: ExtractSNRdataButton
        function ExtractSNRdataButtonPushed(app, event)
            ExtractSNRCallback(app)
        end

        % Value changed function: display_date
        function display_dateValueChanged(app, event)
            DateChangeCallback(app)
        end

        % Value changed function: GPS_satellite_display
        function GPS_satellite_displayValueChanged(app, event)
            satellite = app.GPS_satellite_display.Value;
            band = app.GPS_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GPS_band
        function GPS_bandValueChanged(app, event)
            satellite = app.GPS_satellite_display.Value;
            band = app.GPS_band.Value;
            snr_plot(app,satellite,band)

        end

        % Value changed function: GPS_arc
        function GPS_arcValueChanged(app, event)
            satellite = app.GPS_satellite_display.Value;
            band = app.GPS_band.Value;
            snr_plot(app,satellite,band)

        end

        % Value changed function: GLONASS_satellite_display
        function GLONASS_satellite_displayValueChanged(app, event)
            satellite= app.GLONASS_satellite_display.Value;
            band = app.GLONASS_band.Value;
            snr_plot(app,satellite,band)

        end

        % Value changed function: GLONASS_band
        function GLONASS_bandValueChanged(app, event)
            satellite= app.GLONASS_satellite_display.Value;
            band = app.GLONASS_band.Value;
            snr_plot(app,satellite,band)

        end

        % Value changed function: GLONASS_arc
        function GLONASS_arcValueChanged(app, event)
            satellite= app.GLONASS_satellite_display.Value;
            band = app.GLONASS_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GALILEO_satellite_display
        function GALILEO_satellite_displayValueChanged(app, event)
            satellite= app.GALILEO_satellite_display.Value;
            band = app.GALILEO_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GALILEO_band
        function GALILEO_bandValueChanged(app, event)
            satellite= app.GALILEO_satellite_display.Value;
            band = app.GALILEO_band.Value;
            snr_plot(app,satellite,band)

        end

        % Value changed function: GALILEO_arc
        function GALILEO_arcValueChanged(app, event)
            satellite= app.GALILEO_satellite_display.Value;
            band = app.GALILEO_band.Value;
            snr_plot(app,satellite,band)

        end

        % Value changed function: BDS_satellite_display
        function BDS_satellite_displayValueChanged(app, event)
            satellite= app.BDS_satellite_display.Value;
            band = app.BDS_band.Value;
            snr_plot(app,satellite,band)

        end

        % Value changed function: BDS_band
        function BDS_bandValueChanged(app, event)
            satellite= app.BDS_satellite_display.Value;
            band = app.BDS_band.Value;
            snr_plot(app,satellite,band)

        end

        % Value changed function: BDS_arc
        function BDS_arcValueChanged(app, event)
            satellite= app.BDS_satellite_display.Value;
            band = app.BDS_band.Value;
            snr_plot(app,satellite,band)

        end

        % Value changed function: GNSS_sky
        function GNSS_skyValueChanged(app, event)
            GNSSChangeSkyPlotCallback(app)
        end

        % Value changed function: satellite_sky
        function satellite_skyValueChanged(app, event)
            sat = app.satellite_sky.Value;
            system = string(app.GNSS_sky.Value);
            skyview_plot(app,system,sat)

        end

        % Value changed function: plotmodsnr
        function plotmodsnrValueChanged(app, event)
            value = app.plotmodsnr.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Exit the program?',...
                'Confirm Close');
            switch selection
                case 'OK'
                    delete(app)
                case 'Cancel'
                    return
            end

        end

        % Value changed function: ImportSettingsButton
        function ImportSettingsButtonValueChanged(app, event)
            ImportSettingCallback(app)
        end

        % Button pushed function: load_station_info
        function load_station_infoButtonPushed2(app, event)
            stationInfoCallback(app)
        end

        % Button pushed function: get_station_info
        function get_station_infoButtonPushed2(app, event)
            GeoStationCallback(app)
        end

        % Button pushed function: path_o
        function path_oButtonPushed2(app, event)
            ObsChooseCallback(app)
        end

        % Button pushed function: path_sp3
        function path_sp3ButtonPushed2(app, event)
            EphChooseCallback(app)
        end

        % Button pushed function: getsp3
        function getsp3ButtonPushed2(app, event)
            GetEphCallback(app)
        end

        % Button pushed function: SaveLoadSettingsButton
        function SaveLoadSettingsButtonPushed(app, event)
            SaveSettingCallback(app)
        end

        % Button pushed function: chooseButton_4
        function chooseButton_4Pushed(app, event)
            dname = uigetdir([pwd,'/results/Final_file/'], 'Select the Final file path');
            app.FinalfilepathEditField.Value = dname;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout22 = uigridlayout(app.UIFigure);
            app.TabGroup10 = uitabgroup(app.GridLayout22);
            app.SettingsTab = uitab(app.TabGroup10);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout13_2 = uigridlayout(app.SettingsTab);
            app.TabGroup7 = uitabgroup(app.GridLayout13_2);
            app.StationTab = uitab(app.TabGroup7);
            app.StationTab.Title = 'Station';
            app.GridLayout14_2 = uigridlayout(app.StationTab);
            app.StationinformationPanel = uipanel(app.GridLayout14_2);
            app.StationinformationPanel.Title = 'Station information';
            app.GridLayout15_2 = uigridlayout(app.StationinformationPanel);
            app.LocationinfoPanel = uipanel(app.GridLayout15_2);
            app.LocationinfoPanel.Title = 'Location info';
            app.GridLayout16 = uigridlayout(app.LocationinfoPanel);
            app.XLabel = uilabel(app.GridLayout16);
            app.XLabel.Text = 'X';
            app.X = uieditfield(app.GridLayout16, 'numeric');
            app.X.ValueDisplayFormat = '%.4f';
            app.X.Value = -2168281.1141;
            app.mYLabel = uilabel(app.GridLayout16);
            app.mYLabel.Text = 'Y';
            app.Y = uieditfield(app.GridLayout16, 'numeric');
            app.Y.ValueDisplayFormat = '%.4f';
            app.Y.Value = 4387988.0809;
            app.mZLabel = uilabel(app.GridLayout16);
            app.mZLabel.Text = 'Z';
            app.Z = uieditfield(app.GridLayout16, 'numeric');
            app.Z.ValueDisplayFormat = '%.4f';
            app.Z.Value = 4076014.3417;
            app.LonLabel = uilabel(app.GridLayout16);
            app.LonLabel.Text = 'Lon';
            app.lon = uieditfield(app.GridLayout16, 'text');
            app.lon.Value = '116.2958266369';
            app.LatLabel = uilabel(app.GridLayout16);
            app.LatLabel.Text = 'Lat';
            app.lat = uieditfield(app.GridLayout16, 'text');
            app.lat.Value = '39.9761707123 ';
            app.get_station_info = uibutton(app.GridLayout16, 'push');
            app.get_station_info.ButtonPushedFcn = createCallbackFcn(app, @get_station_infoButtonPushed2, true);
            app.get_station_info.Icon = fullfile(pathToMLAPP, 'icon', '出行导航.png');
            app.get_station_info.Text = 'Map the geographical location';
            app.GeodeticcoordinatesystemmLabel = uilabel(app.GridLayout16);
            app.GeodeticcoordinatesystemmLabel.Text = 'Geodetic coordinate system (m)';
            app.SpacerectangularcoordinatesystemmLabel = uilabel(app.GridLayout16);
            app.SpacerectangularcoordinatesystemmLabel.Text = 'Space rectangular coordinate system (m)';
            app.load_station_info = uibutton(app.GridLayout16, 'push');
            app.load_station_info.ButtonPushedFcn = createCallbackFcn(app, @load_station_infoButtonPushed2, true);
            app.load_station_info.Icon = fullfile(pathToMLAPP, 'icon', '导入.png');
            app.load_station_info.Text = 'Import';
            app.HEditFieldLabel = uilabel(app.GridLayout16);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.GridLayout16, 'text');
            app.HEditField.Value = '87.1290197028';
            app.DropDown_2Label_9 = uilabel(app.GridLayout16);
            app.DropDown_2Label_9.Text = 'Base map type';
            app.basemap = uidropdown(app.GridLayout16);
            app.basemap.Items = {'satellite', 'topographic', 'streets-dark', 'streets'};
            app.basemap.Value = 'satellite';
            app.SettingsofelevationandazimuthPanel = uipanel(app.GridLayout15_2);
            app.SettingsofelevationandazimuthPanel.Title = ' Settings of elevation and azimuth';
            app.GridLayout17 = uigridlayout(app.SettingsofelevationandazimuthPanel);
            app.ElevationminLabel = uilabel(app.GridLayout17);
            app.ElevationminLabel.Text = 'Elevation: min(°)';
            app.elv_min = uieditfield(app.GridLayout17, 'text');
            app.elv_min.Value = '5';
            app.maxLabel = uilabel(app.GridLayout17);
            app.maxLabel.Text = 'max(°)';
            app.elv_max = uieditfield(app.GridLayout17, 'text');
            app.elv_max.Value = '30';
            app.AzimuthminLabel = uilabel(app.GridLayout17);
            app.AzimuthminLabel.Text = 'Azimuth: min(°)';
            app.azi_min = uieditfield(app.GridLayout17, 'text');
            app.azi_min.Value = '0';
            app.EditField_3Label = uilabel(app.GridLayout17);
            app.EditField_3Label.Text = 'max(°)';
            app.azi_max = uieditfield(app.GridLayout17, 'text');
            app.azi_max.Value = '360';
            app.Label_51 = uilabel(app.GridLayout17);
            app.Label_51.Text = 'Azimuth mask(°)';
            app.azimask_min = uieditfield(app.GridLayout17, 'text');
            app.azimask_min.Value = '105';
            app.azimask_max = uieditfield(app.GridLayout17, 'text');
            app.azimask_max.Value = '300';
            app.EditField_3Label_2 = uilabel(app.GridLayout17);
            app.EditField_3Label_2.Text = 'to';
            app.Image8 = uiimage(app.GridLayout15_2);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'icon', 'satellite-tower.2.png');
            app.Label_49 = uilabel(app.GridLayout15_2);
            app.Label_49.Text = 'Name';
            app.station = uieditfield(app.GridLayout15_2, 'text');
            app.station.Value = 'at01';
            app.Label_50 = uilabel(app.GridLayout15_2);
            app.Label_50.Text = 'Belong';
            app.belong = uieditfield(app.GridLayout15_2, 'text');
            app.belong.Value = 'USA';
            app.ImportSettingsButton = uibutton(app.GridLayout15_2, 'state');
            app.ImportSettingsButton.ValueChangedFcn = createCallbackFcn(app, @ImportSettingsButtonValueChanged, true);
            app.ImportSettingsButton.Icon = fullfile(pathToMLAPP, 'icon', '导入.png');
            app.ImportSettingsButton.Text = 'Import Settings';
            app.stationmap = uipanel(app.GridLayout14_2);
            app.stationmap.Title = 'Geographical location diagram of the station';
            app.FilesTab = uitab(app.TabGroup7);
            app.FilesTab.Title = 'Files';
            app.GridLayout18 = uigridlayout(app.FilesTab);
            app.RINEXFilesPanel = uipanel(app.GridLayout18);
            app.RINEXFilesPanel.Title = 'RINEX Files';
            app.GridLayout20 = uigridlayout(app.RINEXFilesPanel);
            app.PathofrinexLabel = uilabel(app.GridLayout20);
            app.PathofrinexLabel.Text = 'Path of rinex: ';
            app.observation = uieditfield(app.GridLayout20, 'text');
            app.path_o = uibutton(app.GridLayout20, 'push');
            app.path_o.ButtonPushedFcn = createCallbackFcn(app, @path_oButtonPushed2, true);
            app.path_o.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.path_o.Text = 'choose';
            app.obsfilenumber = uilabel(app.GridLayout20);
            app.obsfilenumber.Text = 'Selected: 0 files';
            app.Image2 = uiimage(app.GridLayout20);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星(1).png');
            app.rinexversionLabel = uilabel(app.GridLayout20);
            app.rinexversionLabel.Text = 'rinex version';
            app.rinex_version = uidropdown(app.GridLayout20);
            app.rinex_version.Items = {'rinex 2', 'rinex 3'};
            app.rinex_version.Value = 'rinex 3';
            app.Label_52 = uilabel(app.GridLayout20);
            app.Label_52.Text = 'RIENX Files List';
            app.obsfilename = uitextarea(app.GridLayout20);
            app.Label_53 = uilabel(app.GridLayout20);
            app.Label_53.Text = 'Interval';
            app.dt = uieditfield(app.GridLayout20, 'text');
            app.dt.Value = '30';
            app.EphemerisFilesPanel = uipanel(app.GridLayout18);
            app.EphemerisFilesPanel.Title = 'Ephemeris Files';
            app.GridLayout21 = uigridlayout(app.EphemerisFilesPanel);
            app.getsp3 = uibutton(app.GridLayout21, 'push');
            app.getsp3.ButtonPushedFcn = createCallbackFcn(app, @getsp3ButtonPushed2, true);
            app.getsp3.Icon = fullfile(pathToMLAPP, 'icon', '文件下载.png');
            app.getsp3.Text = 'Get ephemeris files';
            app.Pathofsp3Label = uilabel(app.GridLayout21);
            app.Pathofsp3Label.Text = 'Path of sp3: ';
            app.sp3 = uieditfield(app.GridLayout21, 'text');
            app.path_sp3 = uibutton(app.GridLayout21, 'push');
            app.path_sp3.ButtonPushedFcn = createCallbackFcn(app, @path_sp3ButtonPushed2, true);
            app.path_sp3.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.path_sp3.Text = 'choose';
            app.sp3filenumber = uilabel(app.GridLayout21);
            app.sp3filenumber.Text = 'Selected: 0 files';
            app.Image3 = uiimage(app.GridLayout21);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星定位连续运行站点.png');
            app.Label_54 = uilabel(app.GridLayout21);
            app.Label_54.Text = 'Analysis Center:';
            app.sp3_type = uidropdown(app.GridLayout21);
            app.sp3_type.Items = {'com', 'igs', 'COD', 'GFZ'};
            app.sp3_type.Value = 'GFZ';
            app.Label_55 = uilabel(app.GridLayout21);
            app.Label_55.Text = 'Ephemeris Files List';
            app.sp3filename = uitextarea(app.GridLayout21);
            app.TimsPanel = uipanel(app.GridLayout18);
            app.TimsPanel.Title = 'Tims';
            app.GridLayout19 = uigridlayout(app.TimsPanel);
            app.StartdateLabel = uilabel(app.GridLayout19);
            app.StartdateLabel.Text = 'Start date';
            app.starttime = uidatepicker(app.GridLayout19);
            app.starttime.Value = datetime([2023 4 10]);
            app.DatePicker_2Label = uilabel(app.GridLayout19);
            app.DatePicker_2Label.Text = 'End date';
            app.endtime = uidatepicker(app.GridLayout19);
            app.endtime.Value = datetime([2023 4 14]);
            app.Image = uiimage(app.GridLayout19);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon', '日期.png');
            app.Image_2 = uiimage(app.GridLayout19);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'icon', '日期.png');
            app.Image9 = uiimage(app.GridLayout18);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'icon', '全球(1).png');
            app.SaveLoadSettingsButton = uibutton(app.GridLayout18, 'push');
            app.SaveLoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLoadSettingsButtonPushed, true);
            app.SaveLoadSettingsButton.Icon = fullfile(pathToMLAPP, 'icon', '保存.png');
            app.SaveLoadSettingsButton.Text = 'Save & Load Settings';
            app.SNRextractionTab = uitab(app.TabGroup10);
            app.SNRextractionTab.Title = 'SNR extraction';
            app.GridLayout2_2 = uigridlayout(app.SNRextractionTab);
            app.ExtractSNRdataButton = uibutton(app.GridLayout2_2, 'push');
            app.ExtractSNRdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSNRdataButtonPushed, true);
            app.ExtractSNRdataButton.Icon = fullfile(pathToMLAPP, 'icon', '信号提取.png');
            app.ExtractSNRdataButton.Text = 'Extract SNR data';
            app.chooseButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.chooseButton_2.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_2Pushed, true);
            app.chooseButton_2.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_2.Text = 'choose';
            app.ResultpathLabel = uilabel(app.GridLayout2_2);
            app.ResultpathLabel.Text = 'Result path:';
            app.path_SNR = uieditfield(app.GridLayout2_2, 'text');
            app.Label_21 = uilabel(app.GridLayout2_2);
            app.Label_21.Text = 'Date';
            app.display_date = uidropdown(app.GridLayout2_2);
            app.display_date.Items = {};
            app.display_date.ValueChangedFcn = createCallbackFcn(app, @display_dateValueChanged, true);
            app.display_date.Value = {};
            app.Image4_2 = uiimage(app.GridLayout2_2);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'icon', '时间.png');
            app.TabGroup8_2 = uitabgroup(app.GridLayout2_2);
            app.SNRTab_4 = uitab(app.TabGroup8_2);
            app.SNRTab_4.Title = 'SNR';
            app.GridLayout11 = uigridlayout(app.SNRTab_4);
            app.TabGroup2_2 = uitabgroup(app.GridLayout11);
            app.GPSTab_2 = uitab(app.TabGroup2_2);
            app.GPSTab_2.Title = 'GPS';
            app.GridLayout4_2 = uigridlayout(app.GPSTab_2);
            app.GPS_SNR = uiaxes(app.GridLayout4_2);
            title(app.GPS_SNR, 'SNR')
            xlabel(app.GPS_SNR, 'sin(e)')
            ylabel(app.GPS_SNR, 'SNR(dB-Hz)')
            zlabel(app.GPS_SNR, 'Z')
            app.GPS_result = uitable(app.GridLayout4_2);
            app.GPS_result.ColumnName = '';
            app.GPS_result.RowName = {};
            app.ArcLabel = uilabel(app.GridLayout4_2);
            app.ArcLabel.Text = 'Arc';
            app.GPS_arc = uidropdown(app.GridLayout4_2);
            app.GPS_arc.Items = {'1'};
            app.GPS_arc.ValueChangedFcn = createCallbackFcn(app, @GPS_arcValueChanged, true);
            app.GPS_arc.Value = '1';
            app.Image5_5 = uiimage(app.GridLayout4_2);
            app.Image5_5.ImageSource = fullfile(pathToMLAPP, 'icon', '波段选择.png');
            app.Image6_5 = uiimage(app.GridLayout4_2);
            app.Image6_5.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星.png');
            app.Image7_5 = uiimage(app.GridLayout4_2);
            app.Image7_5.ImageSource = fullfile(pathToMLAPP, 'icon', '弧段信息.png');
            app.DropDown_2Label_5 = uilabel(app.GridLayout4_2);
            app.DropDown_2Label_5.Text = 'Sat';
            app.GPS_satellite_display = uidropdown(app.GridLayout4_2);
            app.GPS_satellite_display.Items = {};
            app.GPS_satellite_display.ValueChangedFcn = createCallbackFcn(app, @GPS_satellite_displayValueChanged, true);
            app.GPS_satellite_display.Value = {};
            app.Label_23 = uilabel(app.GridLayout4_2);
            app.Label_23.Text = 'Band';
            app.GPS_band = uidropdown(app.GridLayout4_2);
            app.GPS_band.ValueChangedFcn = createCallbackFcn(app, @GPS_bandValueChanged, true);
            app.GLONASSTab_2 = uitab(app.TabGroup2_2);
            app.GLONASSTab_2.Title = 'GLONASS';
            app.GridLayout5_2 = uigridlayout(app.GLONASSTab_2);
            app.GLONASS_SNR = uiaxes(app.GridLayout5_2);
            title(app.GLONASS_SNR, 'SNR')
            xlabel(app.GLONASS_SNR, 'sin(e)')
            ylabel(app.GLONASS_SNR, 'SNR(dB-Hz)')
            zlabel(app.GLONASS_SNR, 'Z')
            app.GLONASS_result = uitable(app.GridLayout5_2);
            app.GLONASS_result.ColumnName = '';
            app.GLONASS_result.RowName = {};
            app.Image6_6 = uiimage(app.GridLayout5_2);
            app.Image6_6.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星.png');
            app.Image5_6 = uiimage(app.GridLayout5_2);
            app.Image5_6.ImageSource = fullfile(pathToMLAPP, 'icon', '波段选择.png');
            app.Image7_6 = uiimage(app.GridLayout5_2);
            app.Image7_6.ImageSource = fullfile(pathToMLAPP, 'icon', '弧段信息.png');
            app.DropDown_2Label_6 = uilabel(app.GridLayout5_2);
            app.DropDown_2Label_6.Text = 'Sat';
            app.GLONASS_satellite_display = uidropdown(app.GridLayout5_2);
            app.GLONASS_satellite_display.Items = {};
            app.GLONASS_satellite_display.ValueChangedFcn = createCallbackFcn(app, @GLONASS_satellite_displayValueChanged, true);
            app.GLONASS_satellite_display.Value = {};
            app.Label_24 = uilabel(app.GridLayout5_2);
            app.Label_24.Text = 'Band';
            app.GLONASS_band = uidropdown(app.GridLayout5_2);
            app.GLONASS_band.ValueChangedFcn = createCallbackFcn(app, @GLONASS_bandValueChanged, true);
            app.Label_25 = uilabel(app.GridLayout5_2);
            app.Label_25.Text = 'Arc';
            app.GLONASS_arc = uidropdown(app.GridLayout5_2);
            app.GLONASS_arc.Items = {'1'};
            app.GLONASS_arc.ValueChangedFcn = createCallbackFcn(app, @GLONASS_arcValueChanged, true);
            app.GLONASS_arc.Value = '1';
            app.GALILEOTab_2 = uitab(app.TabGroup2_2);
            app.GALILEOTab_2.Title = 'GALILEO';
            app.GridLayout6_2 = uigridlayout(app.GALILEOTab_2);
            app.GALILEO_SNR = uiaxes(app.GridLayout6_2);
            title(app.GALILEO_SNR, 'SNR')
            xlabel(app.GALILEO_SNR, 'sin(e)')
            ylabel(app.GALILEO_SNR, 'SNR(dB-Hz)')
            zlabel(app.GALILEO_SNR, 'Z')
            app.GALILEO_result = uitable(app.GridLayout6_2);
            app.GALILEO_result.ColumnName = '';
            app.GALILEO_result.RowName = {};
            app.Image6_7 = uiimage(app.GridLayout6_2);
            app.Image6_7.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星.png');
            app.Image5_7 = uiimage(app.GridLayout6_2);
            app.Image5_7.ImageSource = fullfile(pathToMLAPP, 'icon', '波段选择.png');
            app.Image7_7 = uiimage(app.GridLayout6_2);
            app.Image7_7.ImageSource = fullfile(pathToMLAPP, 'icon', '弧段信息.png');
            app.DropDown_2Label_7 = uilabel(app.GridLayout6_2);
            app.DropDown_2Label_7.Text = 'Sat';
            app.GALILEO_satellite_display = uidropdown(app.GridLayout6_2);
            app.GALILEO_satellite_display.Items = {};
            app.GALILEO_satellite_display.ValueChangedFcn = createCallbackFcn(app, @GALILEO_satellite_displayValueChanged, true);
            app.GALILEO_satellite_display.Value = {};
            app.Label_26 = uilabel(app.GridLayout6_2);
            app.Label_26.Text = 'Band';
            app.GALILEO_band = uidropdown(app.GridLayout6_2);
            app.GALILEO_band.ValueChangedFcn = createCallbackFcn(app, @GALILEO_bandValueChanged, true);
            app.Label_27 = uilabel(app.GridLayout6_2);
            app.Label_27.Text = 'Arc';
            app.GALILEO_arc = uidropdown(app.GridLayout6_2);
            app.GALILEO_arc.Items = {'1'};
            app.GALILEO_arc.ValueChangedFcn = createCallbackFcn(app, @GALILEO_arcValueChanged, true);
            app.GALILEO_arc.Value = '1';
            app.BDSTab_2 = uitab(app.TabGroup2_2);
            app.BDSTab_2.Title = 'BDS';
            app.GridLayout7_2 = uigridlayout(app.BDSTab_2);
            app.BDS_SNR = uiaxes(app.GridLayout7_2);
            title(app.BDS_SNR, 'SNR')
            xlabel(app.BDS_SNR, 'sin(e)')
            ylabel(app.BDS_SNR, 'SNR(dB-Hz)')
            zlabel(app.BDS_SNR, 'Z')
            app.BDS_result = uitable(app.GridLayout7_2);
            app.BDS_result.ColumnName = '';
            app.BDS_result.RowName = {};
            app.Image6_8 = uiimage(app.GridLayout7_2);
            app.Image6_8.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星.png');
            app.Image5_8 = uiimage(app.GridLayout7_2);
            app.Image5_8.ImageSource = fullfile(pathToMLAPP, 'icon', '波段选择.png');
            app.Image7_8 = uiimage(app.GridLayout7_2);
            app.Image7_8.ImageSource = fullfile(pathToMLAPP, 'icon', '弧段信息.png');
            app.DropDown_2Label_8 = uilabel(app.GridLayout7_2);
            app.DropDown_2Label_8.Text = 'Sat';
            app.BDS_satellite_display = uidropdown(app.GridLayout7_2);
            app.BDS_satellite_display.Items = {};
            app.BDS_satellite_display.ValueChangedFcn = createCallbackFcn(app, @BDS_satellite_displayValueChanged, true);
            app.BDS_satellite_display.Value = {};
            app.Label_28 = uilabel(app.GridLayout7_2);
            app.Label_28.Text = 'Band';
            app.BDS_band = uidropdown(app.GridLayout7_2);
            app.BDS_band.ValueChangedFcn = createCallbackFcn(app, @BDS_bandValueChanged, true);
            app.Label_29 = uilabel(app.GridLayout7_2);
            app.Label_29.Text = 'Arc';
            app.BDS_arc = uidropdown(app.GridLayout7_2);
            app.BDS_arc.Items = {'1'};
            app.BDS_arc.ValueChangedFcn = createCallbackFcn(app, @BDS_arcValueChanged, true);
            app.BDS_arc.Value = '1';
            app.SkymapTab = uitab(app.TabGroup8_2);
            app.SkymapTab.Title = 'Sky map';
            app.GridLayout12 = uigridlayout(app.SkymapTab);
            app.skyview_2 = uitabgroup(app.GridLayout12);
            app.sky_all = uitab(app.skyview_2);
            app.sky_all.Title = 'All satellite arcs';
            app.sky_selected = uitab(app.skyview_2);
            app.sky_selected.Title = 'Active satellite arcs';
            app.GNSSLabel = uilabel(app.GridLayout12);
            app.GNSSLabel.Text = 'GNSS';
            app.GNSS_sky = uilistbox(app.GridLayout12);
            app.GNSS_sky.Items = {'GPS', 'GLONASS', 'GALILEO', 'BDS'};
            app.GNSS_sky.ValueChangedFcn = createCallbackFcn(app, @GNSS_skyValueChanged, true);
            app.GNSS_sky.Value = 'GPS';
            app.Label_30 = uilabel(app.GridLayout12);
            app.Label_30.Text = '卫星';
            app.satellite_sky = uilistbox(app.GridLayout12);
            app.satellite_sky.ValueChangedFcn = createCallbackFcn(app, @satellite_skyValueChanged, true);
            app.InversemodelingTab = uitab(app.TabGroup10);
            app.InversemodelingTab.Title = 'Inverse modeling';
            app.GridLayout13 = uigridlayout(app.InversemodelingTab);
            app.SNR_model = uiaxes(app.GridLayout13);
            title(app.SNR_model, 'SNR residual sequence Modeling vs. Real ')
            xlabel(app.SNR_model, 'sin(e)')
            ylabel(app.SNR_model, 'SNR residual sequence')
            zlabel(app.SNR_model, 'Z')
            app.chooseButton_3 = uibutton(app.GridLayout13, 'push');
            app.chooseButton_3.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_3Pushed, true);
            app.chooseButton_3.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_3.Text = 'choose';
            app.path_SNR_data = uieditfield(app.GridLayout13, 'text');
            app.GNSSPanel = uipanel(app.GridLayout13);
            app.GNSSPanel.Title = 'GNSS';
            app.GridLayout15 = uigridlayout(app.GNSSPanel);
            app.GPSCheckBox = uicheckbox(app.GridLayout15);
            app.GPSCheckBox.Text = 'GPS';
            app.GPSCheckBox.Value = true;
            app.GLONASSCheckBox = uicheckbox(app.GridLayout15);
            app.GLONASSCheckBox.Text = 'GLONASS';
            app.GLONASSCheckBox.Value = true;
            app.GALILEOCheckBox = uicheckbox(app.GridLayout15);
            app.GALILEOCheckBox.Text = 'GALILEO';
            app.GALILEOCheckBox.Value = true;
            app.BDSCheckBox = uicheckbox(app.GridLayout15);
            app.BDSCheckBox.Text = 'BDS';
            app.BDSCheckBox.Value = true;
            app.chose_path_for_inversse = uibutton(app.GridLayout13, 'push');
            app.chose_path_for_inversse.ButtonPushedFcn = createCallbackFcn(app, @chose_path_for_inversseButtonPushed, true);
            app.chose_path_for_inversse.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chose_path_for_inversse.Text = 'choose';
            app.inverse_analysis_result_path = uieditfield(app.GridLayout13, 'text');
            app.EditField_2Label = uilabel(app.GridLayout13);
            app.EditField_2Label.Text = 'SNR Path';
            app.SavePathLabel = uilabel(app.GridLayout13);
            app.SavePathLabel.Text = 'Save Path';
            app.ModelingoptionsPanel = uipanel(app.GridLayout13);
            app.ModelingoptionsPanel.Title = 'Modeling options';
            app.GridLayout14 = uigridlayout(app.ModelingoptionsPanel);
            app.Label_4 = uilabel(app.GridLayout14);
            app.Label_4.Text = 'Initial :';
            app.roughness = uieditfield(app.GridLayout14, 'text');
            app.roughness.Value = '0.1';
            app.ResultsLabel = uilabel(app.GridLayout14);
            app.ResultsLabel.Text = 'Results:';
            app.roughness_adjustment = uieditfield(app.GridLayout14, 'text');
            app.roughness_adjustment.Value = 'nan';
            app.EditField_5Label_2 = uilabel(app.GridLayout14);
            app.EditField_5Label_2.Text = 'Window length (hour)';
            app.tlen_hours = uieditfield(app.GridLayout14, 'numeric');
            app.tlen_hours.Value = 18;
            app.Label_2 = uilabel(app.GridLayout14);
            app.Label_2.Text = 'Window Interval (hour)';
            app.kspac_hours = uieditfield(app.GridLayout14, 'numeric');
            app.kspac_hours.Value = 3;
            app.Label_7 = uilabel(app.GridLayout14);
            app.Label_7.Text = 'Antenna height(m)';
            app.ahgt = uieditfield(app.GridLayout14, 'numeric');
            app.ahgt.Value = 6.48;
            app.mLabel = uilabel(app.GridLayout14);
            app.mLabel.Text = 'Range(m)';
            app.ahgt_bounds = uieditfield(app.GridLayout14, 'numeric');
            app.ahgt_bounds.Value = 1.2;
            app.Label_3 = uilabel(app.GridLayout14);
            app.Label_3.Text = 'Roughness';
            app.rough_sw = uiswitch(app.GridLayout14, 'slider');
            app.inverse_model = uibutton(app.GridLayout13, 'state');
            app.inverse_model.ValueChangedFcn = createCallbackFcn(app, @inverse_modelValueChanged, true);
            app.inverse_model.Icon = fullfile(pathToMLAPP, 'icon', '数据建模.png');
            app.inverse_model.Text = 'Modeling';
            app.RunningstateLampLabel = uilabel(app.GridLayout13);
            app.RunningstateLampLabel.Text = 'Running state';
            app.RunningstateLamp = uilamp(app.GridLayout13);
            app.Label_46 = uilabel(app.GridLayout13);
            app.Label_46.Text = 'Plot';
            app.plotmodsnr = uiswitch(app.GridLayout13, 'slider');
            app.plotmodsnr.ValueChangedFcn = createCallbackFcn(app, @plotmodsnrValueChanged, true);
            app.TidallevelverificationTab = uitab(app.TabGroup10);
            app.TidallevelverificationTab.Title = 'Tidal level verification';
            app.GridLayout23 = uigridlayout(app.TidallevelverificationTab);
            app.inverse_results = uiaxes(app.GridLayout23);
            xlabel(app.inverse_results, 'Time')
            ylabel(app.inverse_results, 'Water Level (m)')
            zlabel(app.inverse_results, 'Z')
            app.chooseButton = uibutton(app.GridLayout23, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.chooseButton.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton.Text = 'choose';
            app.Label_5 = uilabel(app.GridLayout23);
            app.Label_5.Text = 'Tidal Data: ';
            app.tide_path = uieditfield(app.GridLayout23, 'text');
            app.InverseModelingrmscmLabel = uilabel(app.GridLayout23);
            app.InverseModelingrmscmLabel.Text = 'Inverse Mode rms(cm):';
            app.rms_inv = uieditfield(app.GridLayout23, 'numeric');
            app.rmscmLabel = uilabel(app.GridLayout23);
            app.rmscmLabel.Text = 'Spectral   rms(cm):';
            app.rms_sp = uieditfield(app.GridLayout23, 'numeric');
            app.cor = uicheckbox(app.GridLayout23);
            app.cor.Text = 'Correlation scatter plot';
            app.cor.Value = true;
            app.DH = uicheckbox(app.GridLayout23);
            app.DH.Text = 'Residual histogram';
            app.DH.Value = true;
            app.DrawingoptionsLabel = uilabel(app.GridLayout23);
            app.DrawingoptionsLabel.Text = 'Drawing options:';
            app.result_scatter = uibutton(app.GridLayout23, 'state');
            app.result_scatter.ValueChangedFcn = createCallbackFcn(app, @result_scatterValueChanged, true);
            app.result_scatter.Icon = fullfile(pathToMLAPP, 'icon', '绘制设置.png');
            app.result_scatter.Text = 'Plot';
            app.FinalfilepathEditFieldLabel = uilabel(app.GridLayout23);
            app.FinalfilepathEditFieldLabel.Text = 'Final file path';
            app.FinalfilepathEditField = uieditfield(app.GridLayout23, 'text');
            app.Label_56 = uilabel(app.GridLayout23);
            app.Label_56.Text = 'Average daily inversion points:';
            app.point_day = uieditfield(app.GridLayout23, 'numeric');
            app.chooseButton_4 = uibutton(app.GridLayout23, 'push');
            app.chooseButton_4.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_4Pushed, true);
            app.chooseButton_4.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_4.Text = 'choose';
            app.InversemodelinginversionLabel = uilabel(app.GridLayout22);
            app.InversemodelinginversionLabel.Text = 'Inverse modeling inversion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNR_Inverse_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end