classdef SNR_spectral_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SpectralanalysisinversionLabel  matlab.ui.control.Label
        TabGroup9                       matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        TabGroup7                       matlab.ui.container.TabGroup
        StationTab                      matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        stationmap                      matlab.ui.container.Panel
        StationinformationPanel         matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        ImportSettingsButton            matlab.ui.control.StateButton
        belong                          matlab.ui.control.EditField
        Label_22                        matlab.ui.control.Label
        station                         matlab.ui.control.EditField
        Label_21                        matlab.ui.control.Label
        Image8                          matlab.ui.control.Image
        SettingsofelevationandazimuthPanel  matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        EditField_3Label_3              matlab.ui.control.Label
        azimask_max                     matlab.ui.control.EditField
        azimask_min                     matlab.ui.control.EditField
        Label_28                        matlab.ui.control.Label
        azi_max                         matlab.ui.control.EditField
        EditField_3Label_2              matlab.ui.control.Label
        azi_min                         matlab.ui.control.EditField
        AzimuthminLabel                 matlab.ui.control.Label
        elv_max                         matlab.ui.control.EditField
        maxLabel                        matlab.ui.control.Label
        elv_min                         matlab.ui.control.EditField
        ElevationminLabel               matlab.ui.control.Label
        LocationinfoPanel               matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        basemap                         matlab.ui.control.DropDown
        DropDown_2Label_5               matlab.ui.control.Label
        HEditField                      matlab.ui.control.EditField
        HEditFieldLabel                 matlab.ui.control.Label
        load_station_info               matlab.ui.control.Button
        SpacerectangularcoordinatesystemmLabel  matlab.ui.control.Label
        GeodeticcoordinatesystemmLabel  matlab.ui.control.Label
        get_station_info                matlab.ui.control.Button
        lat                             matlab.ui.control.EditField
        LatLabel                        matlab.ui.control.Label
        lon                             matlab.ui.control.EditField
        LonLabel                        matlab.ui.control.Label
        Z                               matlab.ui.control.NumericEditField
        mZLabel                         matlab.ui.control.Label
        Y                               matlab.ui.control.NumericEditField
        mYLabel                         matlab.ui.control.Label
        X                               matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        FilesTab                        matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        SaveLoadSettingsButton          matlab.ui.control.Button
        Image9                          matlab.ui.control.Image
        TimsPanel                       matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        endtime                         matlab.ui.control.DatePicker
        DatePicker_2Label               matlab.ui.control.Label
        starttime                       matlab.ui.control.DatePicker
        StartdateLabel                  matlab.ui.control.Label
        EphemerisFilesPanel             matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        sp3filename                     matlab.ui.control.TextArea
        Label_34                        matlab.ui.control.Label
        sp3_type                        matlab.ui.control.DropDown
        Label_36                        matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        sp3filenumber                   matlab.ui.control.Label
        path_sp3                        matlab.ui.control.Button
        sp3                             matlab.ui.control.EditField
        Pathofsp3Label                  matlab.ui.control.Label
        getsp3                          matlab.ui.control.Button
        RINEXFilesPanel                 matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        dt                              matlab.ui.control.EditField
        Label_32                        matlab.ui.control.Label
        obsfilename                     matlab.ui.control.TextArea
        Label_31                        matlab.ui.control.Label
        rinex_version                   matlab.ui.control.DropDown
        rinexversionLabel               matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        obsfilenumber                   matlab.ui.control.Label
        path_o                          matlab.ui.control.Button
        observation                     matlab.ui.control.EditField
        PathofrinexLabel                matlab.ui.control.Label
        SNRextractionTab                matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup8                       matlab.ui.container.TabGroup
        SNRTab_2                        matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        GPSTab                          matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        GPS_band                        matlab.ui.control.DropDown
        Label_12                        matlab.ui.control.Label
        GPS_satellite_display           matlab.ui.control.DropDown
        satelliteLabel                  matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        GPS_arc                         matlab.ui.control.DropDown
        ArcLabel                        matlab.ui.control.Label
        GPS_result                      matlab.ui.control.Table
        GPS_SNR                         matlab.ui.control.UIAxes
        GLONASSTab                      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        GLONASS_arc                     matlab.ui.control.DropDown
        Label_15                        matlab.ui.control.Label
        GLONASS_band                    matlab.ui.control.DropDown
        Label_14                        matlab.ui.control.Label
        GLONASS_satellite_display       matlab.ui.control.DropDown
        DropDown_2Label_2               matlab.ui.control.Label
        Image7_2                        matlab.ui.control.Image
        Image5_2                        matlab.ui.control.Image
        Image6_2                        matlab.ui.control.Image
        GLONASS_result                  matlab.ui.control.Table
        GLONASS_SNR                     matlab.ui.control.UIAxes
        GALILEOTab                      matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GALILEO_arc                     matlab.ui.control.DropDown
        Label_17                        matlab.ui.control.Label
        GALILEO_band                    matlab.ui.control.DropDown
        Label_16                        matlab.ui.control.Label
        GALILEO_satellite_display       matlab.ui.control.DropDown
        DropDown_2Label_3               matlab.ui.control.Label
        Image7_3                        matlab.ui.control.Image
        Image5_3                        matlab.ui.control.Image
        Image6_3                        matlab.ui.control.Image
        GALILEO_result                  matlab.ui.control.Table
        GALILEO_SNR                     matlab.ui.control.UIAxes
        BDSTab                          matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        BDS_arc                         matlab.ui.control.DropDown
        Label_19                        matlab.ui.control.Label
        BDS_band                        matlab.ui.control.DropDown
        Label_18                        matlab.ui.control.Label
        BDS_satellite_display           matlab.ui.control.DropDown
        DropDown_2Label_4               matlab.ui.control.Label
        Image7_4                        matlab.ui.control.Image
        Image5_4                        matlab.ui.control.Image
        Image6_4                        matlab.ui.control.Image
        BDS_result                      matlab.ui.control.Table
        BDS_SNR                         matlab.ui.control.UIAxes
        SkymapTab                       matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        satellite_sky                   matlab.ui.control.ListBox
        Label_20                        matlab.ui.control.Label
        GNSS_sky                        matlab.ui.control.ListBox
        GNSSLabel                       matlab.ui.control.Label
        skyview                         matlab.ui.container.TabGroup
        sky_all                         matlab.ui.container.Tab
        sky_selected                    matlab.ui.container.Tab
        Image4                          matlab.ui.control.Image
        display_date                    matlab.ui.control.DropDown
        Label_11                        matlab.ui.control.Label
        path_SNR                        matlab.ui.control.EditField
        ResultpathLabel                 matlab.ui.control.Label
        chooseButton                    matlab.ui.control.Button
        ExtractSNRdataButton            matlab.ui.control.Button
        WaterlevelinversionTab          matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        SlidingwindowSettingsPanel      matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        win_gap                         matlab.ui.control.NumericEditField
        windowlengthLabel               matlab.ui.control.Label
        win_length                      matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        window_choose                   matlab.ui.control.Switch
        Label_3                         matlab.ui.control.Label
        ProgressbarLabel                matlab.ui.control.Label
        RunningstatusLamp               matlab.ui.control.Lamp
        RunningstatusLampLabel          matlab.ui.control.Label
        check_fen                       matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        fresnel_kml                     matlab.ui.control.Switch
        kmlLabel                        matlab.ui.control.Label
        ispic                           matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        RangemEditField                 matlab.ui.control.NumericEditField
        RangemEditFieldLabel            matlab.ui.control.Label
        antenna_height                  matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        decimates                       matlab.ui.control.NumericEditField
        intervalsLabel                  matlab.ui.control.Label
        trop                            matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        analysis_result_path            matlab.ui.control.EditField
        SavePathLabel                   matlab.ui.control.Label
        chose_path_for_LSP              matlab.ui.control.Button
        analysis                        matlab.ui.control.Button
        SNRPanel                        matlab.ui.container.Panel
        LSP_result                      matlab.ui.container.Panel
        chooseButton_2                  matlab.ui.control.Button
        path_SNR_data                   matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        analysis_result                 matlab.ui.control.Table
        ProcessBar                      matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        TidallevelverificationTab       matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        tide_path                       matlab.ui.control.EditField
        Label_5                         matlab.ui.control.Label
        result_scatter                  matlab.ui.control.StateButton
        chooseButton_3                  matlab.ui.control.Button
        results                         matlab.ui.control.UIAxes
        TidalcorrectionTab              matlab.ui.container.Tab
        GridLayout23                    matlab.ui.container.GridLayout
        FinalfilepathEditField          matlab.ui.control.EditField
        FinalfilepathEditFieldLabel     matlab.ui.control.Label
        chooseButton_4                  matlab.ui.control.Button
        CorrectPlotButton               matlab.ui.control.StateButton
        RMSEcmPanel                     matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        after                           matlab.ui.control.EditField
        EditField_5Label_2              matlab.ui.control.Label
        before                          matlab.ui.control.EditField
        Label_7                         matlab.ui.control.Label
        point_day                       matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        DrawingoptionsPanel             matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        cor                             matlab.ui.control.CheckBox
        DH                              matlab.ui.control.CheckBox
        SPD                             matlab.ui.control.CheckBox
        band_com                        matlab.ui.control.CheckBox
        BA                              matlab.ui.control.CheckBox
        tidal_constituent               matlab.ui.container.ButtonGroup
        all145tide                      matlab.ui.control.RadioButton
        O1K1N2M2S2                      matlab.ui.control.RadioButton
        ans_dongtai                     matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function drawProgressBar(app, progress)
            barh(app.ProcessBar, progress,'EdgeColor','none','FaceColor','g');
            ylim(app.ProcessBar, [0.5 1.5]);
            xlim(app.ProcessBar, [0 1]);
            app.ProcessBar.XTick = [];
            app.ProcessBar.YTick = [];
            app.ProcessBar.XColor = 'none';
            app.ProcessBar.YColor = 'none';
            drawnow
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.SNRPanel.Title = sprintf('%c SNR', 948);
        end

        % Button pushed function: analysis
        function analysisButtonPushed(app, event)
            try

                app.RunningstatusLamp.Color = 'g';
                drawProgressBar(app, 0);
                drawnow
                global Operation_settings

                time = Operation_settings.time;

                stations = Operation_settings.station_name;
                satconsts = zeros(1,4);
                satconsts(1,1) = 1;
                % trop
                if string(app.trop.Value) == "None"
                    tropd = 0;
                elseif string(app.trop.Value) == "Williams"
                    tropd = 1;
                elseif string(app.trop.Value) == "Santamaria-Gomez"
                    tropd = 2;
                end

                startdate = datenum(time(1));
                enddate = datenum(time(2));
                decimate = app.decimates.Value;
                tdatenum = startdate-1;
                slvlr_all = zeros(0,20);
                if string(app.ispic.Value) == "Yes"
                    tempsnr = 1;
                    templsp = 1;
                else
                    tempsnr = 0;
                    templsp = 0;
                end
                if string(app.fresnel_kml.Value) == "Yes"
                    fresout=[pwd,'\results\fresnel_kml\'];
                    if isfolder(fresout)
                        files = dir(fullfile(fresout, '*'));
                        files = files(~[files.isdir]);

                        for i = 1:length(files)
                            delete(fullfile(fresout, files(i).name));
                        end
                    end
                end
                % axes setting
                children = app.LSP_result.Children;
                delete(children);
                children = app.SNRPanel.Children;
                delete(children);
                if string(app.ispic.Value) == "Yes"
                    axes_dsnr = axes(app.SNRPanel);
                    axes_lsp = axes(app.LSP_result);
                else
                    axes_dsnr = [];
                    axes_lsp = [];
                end


                totalIterations = (enddate-startdate+1) * 4;
                currentIteration  = 0;
                while tdatenum < enddate
                    tdatenum = tdatenum+1;
                    % LOAD SNR DATA
                    curdt = datetime(tdatenum,'convertfrom','datenum');
                    disp(char(curdt))
                    load([app.path_SNR_data.Value, '\', stations, num2str(tdatenum),'.mat'])
                    snrfile = snr_data;


                    tempfresnel = 0;

                    for sys = 1:4
                        if sys == 1
                            if isfield(snr_data,'GPS')
                                system = snr_data.GPS;
                                gnss_system = "GPS";
                            else
                                continue
                            end
                        elseif sys == 2
                            if isfield(snr_data,'GLONASS')
                                system = snr_data.GLONASS;
                                gnss_system = "GLONASS";
                            else
                                continue
                            end

                        elseif sys == 3
                            if isfield(snr_data,'GALILEO')
                                system = snr_data.GALILEO;
                                gnss_system = "GALILEO";
                            else
                                continue
                            end

                        elseif sys == 4
                            if isfield(snr_data,'BDS')
                                system = snr_data.BDS;
                                gnss_system = "BDS";
                            else
                                continue
                            end

                        end
                        band_num = size(system.Properties.VariableNames);

                        for l = 1:band_num(2)-4
                            band = system.Properties.VariableNames(l+4);
                            [hang,~] = size(system);
                            nan_all = nan(hang ,2);
                            snrfile = [table2array(system(:,1)),table2array(system(:,4)),table2array(system(:,3)),table2array(system(:,2)),nan_all,table2array(system(:,band))];

                            [slvlr,lspy] = analyzesnr_fun(app,snrfile,tdatenum,tropd,decimate,...
                                tempsnr,templsp,axes_dsnr,axes_lsp,gnss_system,cell2mat(band));
                            sizes = size(slvlr);
                            if sizes(1) ~= 0

                                scatter(app.UIAxes,slvlr(:,1),slvlr(:,7),'filled')
                                legend(app.UIAxes, cell2mat(band))
                                datetick(app.UIAxes,'x','dd-mmm-yyyy')
                                xlim(app.UIAxes,[datenum(time(1)) datenum(time(2)+1)])

                            end
                            slvlr_all = [slvlr_all; slvlr];
                        end
                        currentIteration = currentIteration+1;
                        drawProgressBar(app, currentIteration/totalIterations);
                    end
                end
                % now save the output
                save([app.analysis_result_path.Value,'/',num2str(tdatenum),'.mat'],'slvlr','lspy')
                slvlr_all = [slvlr_all;slvlr];

                drawProgressBar(app, (tdatenum-startdate+1)/(enddate-startdate+1));


                Time = datetime(slvlr_all(:,1),'ConvertFrom','datenum');
                GNSS = char(slvlr_all(:,14));
                Band = char(slvlr_all(:,11:13));
                app.analysis_result.Data = horzcat(table(Time,GNSS,Band),array2table(slvlr_all(:,2:10))); % display the result
                save([app.analysis_result_path.Value,'/',num2str(tdatenum),'slvlr_all.mat'],'slvlr_all')

                disp("end")
                app.RunningstatusLamp.Color = 'r';

            catch ME
                app.RunningstatusLamp.Color = 'r';
                fig = app.UIFigure;
                uialert(fig,[ME.message,newline,'File:',ME.stack(1,:).file,newline,'Name:',...
                    ME.stack(1,:).name,newline,'Line:',num2str(ME.stack(1,:).line)],'warning')
                return
            end
        end

        % Callback function
        function Button_4Pushed(app, event)

        end

        % Button pushed function: chooseButton_2
        function chooseButton_2Pushed(app, event)
            dname = uigetdir(pwd);
            app.path_SNR_data.Value = dname;
            return
        end

        % Button pushed function: chose_path_for_LSP
        function chose_path_for_LSPButtonPushed(app, event)
            dname = uigetdir(pwd);
            app.analysis_result_path.Value = dname;
            return
        end

        % Button pushed function: chooseButton_3
        function chooseButton_3Pushed(app, event)
            [fileNames, filepath] = uigetfile('tide_data/*.*', 'Select the tide file');
            app.tide_path.Value = strcat(filepath,'/',fileNames);
        end

        % Value changed function: result_scatter
        function result_scatterValueChanged(app, event)
            global Operation_settings

            time       = Operation_settings.time;
            start_date = datenum(time(1));
            end_date   = datenum(time(2));
            path       = app.tide_path.Value;
            load(path)

            plot(app.results, xaxis,slvl,'black','DisplayName','Tide gauge');
            xlim(app.results, [datenum(time(1)),datenum(time(2))+1])
            tide = unique([xaxis, slvl],"rows");
            xaxis = tide(:,1);
            slvl  = tide(:,2);
            tide_indx = xaxis>=start_date & xaxis<=end_date+1;
            time_tide = datetime(xaxis(tide_indx),'ConvertFrom','datenum');
            sea_level_tide = slvl(tide_indx);

            % 绘制结果
            enddate = datenum(time(2));
            tdatenum = enddate;
            load([app.analysis_result_path.Value, '/', num2str(tdatenum), 'slvlr_all.mat'])

            % 两张图，一张分开系统，一张总体预览
            figure
            tiledlayout(4,5,'TileSpacing','compact');
            ax = app.results;
            hold(ax,'off')
            hold(ax,'on')
            for i = 1:4
                if i == 1
                    sys = double('G');
                    systemchar = 'GPS';
                elseif i == 2
                    sys = double('R');
                    systemchar = 'GLONASS';
                elseif i == 3
                    sys = double('E');
                    systemchar = 'GALILEO';
                elseif i == 4
                    sys = double('C');
                    systemchar = 'BDS';
                end
                nexttile([1,4])
                hold on
                plot(xaxis,slvl,'black','DisplayName','Tide gauge')
                xlim([datenum(time(1)),datenum(time(2))+1])

                indx = slvlr_all(:,14)==sys;
                data_sys = slvlr_all(indx,:);
                band = unique(string(char(data_sys(:,11:13))));
                [band_numbers,~] = size(band);

                for j = 1:band_numbers
                    % 总体图
                    band_name = char(band(j));
                    indx_band = string(char(data_sys(:,11:13)))==string(band_name);
                    data_band = data_sys(indx_band,:);

                    data_band = sortrows(data_band);
                    mrk_list = ["o","+","*","pentagram","^","square"];
                    mrk = mrk_list(randi(length(mrk_list)));
                    mrk = string(cell2mat(cellstr(mrk)));
                    scatter(ax,data_band(:,1), app.antenna_height.Value-data_band(:,7),mrk, DisplayName=string(strcat(systemchar,':',band_name)))

                    % 分系统图
                    scatter(data_band(:,1), app.antenna_height.Value-data_band(:,7),mrk, DisplayName=string(strcat(systemchar,':',band_name)))
                    datetick('x','yyyy-mm-dd', 'keepticks')
                    if i == 4
                        xlabel('Time')
                    end
                    title(systemchar)
                    ylabel('Water Level(m)')
                    sea_level_ir_band = app.antenna_height.Value-data_band(:,7);
                    time_band = data_band(:,1);
                    repeat_time = find(diff(time_band)==0);
                    time_band(repeat_time) = [];
                    sea_level_ir_band(repeat_time) = [];
                    interp_tide = interp1(datenum(time_tide),sea_level_tide,time_band,"linear");
                    valid_indx = ~isnan(interp_tide) & ~isnan(sea_level_ir_band);
                    RMSE(j) = sqrt(mean((interp_tide(valid_indx)-sea_level_ir_band(valid_indx)).^2));
                    valid_num(j) = numel(sea_level_ir_band);
                end
                legend
                box on
                datetick(ax,'x','yyyy-mm-dd', 'keepticks')
                nexttile
                b = barh(RMSE*100,0.5, 'FaceColor',[0.3010 0.7450 0.9330]);
                set(gca, 'YTickLabel', band)
                xlabel('RMSE(cm)')
                xtips1 = b(1).YEndPoints + 0.01;
                ytips1 = b(1).XEndPoints;
                rms_band = string(b(1).YData);
                text(xtips1,ytips1,rms_band,'HorizontalAlignment','right','Color','r')
                text(xtips1,ytips1,string(valid_num/(end_date-start_date+1)),'HorizontalAlignment','left','Color',[0.4660 0.6740 0.1880])

                xlim([0, max(RMSE)*100+5])

                legend(ax, 'NumColumns', 5)
                datetick(ax,'x','yyyy-mm-dd', 'keepticks')
                xlim(ax,[datenum(time(1)) datenum(time(2)+1)])
                ylim(ax,[-1.5,1])

            end
            hold(ax,'off')
        end

        % Callback function
        function tide_plotButtonPushed(app, event)
            global time
            path = app.tide_path.Value;
            load(path)
            plot(app.results, xaxis,slvl,'black','DisplayName','Tide Data');
            xlim(app.results,[datenum(time(1)),datenum(time(2))+1])
        end

        % Value changed function: CorrectPlotButton
        function CorrectPlotButtonValueChanged(app, event)
            global Operation_settings

            slvlrdir = app.analysis_result_path.Value;
            tgstring = app.tide_path.Value;
            if app.all145tide.Value == 1
                redconstits = 0;
            elseif app.O1K1N2M2S2.Value == 1
                redconstits = 1;
            end
            makefig = 1;

            Final_file_path = app.FinalfilepathEditField.Value;
            [t_rh,rh_adj,rh_nadj,rms_adj,rms_nadj] = spectralanalysis_kl(app,slvlrdir,tgstring,redconstits, makefig, Final_file_path);
            app.before.Value = strcat(num2str(rms_nadj * 100),'cm');
            app.after.Value = strcat(num2str(rms_adj * 100),'cm');
        end

        % Callback function
        function Button_5Pushed(app, event)

        end

        % Button pushed function: chooseButton
        function chooseButtonPushed(app, event)
            dname = uigetdir(pwd);
            app.path_SNR.Value = dname;
            return
        end

        % Button pushed function: ExtractSNRdataButton
        function ExtractSNRdataButtonPushed(app, event)
            ExtractSNRCallback(app)
        end

        % Value changed function: display_date
        function display_dateValueChanged(app, event)
            DateChangeCallback(app)
        end

        % Value changed function: BDS_satellite_display
        function BDS_satellite_displayValueChanged(app, event)
            satellite= app.BDS_satellite_display.Value;
            band = app.BDS_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GPS_satellite_display
        function GPS_satellite_displayValueChanged(app, event)
            satellite = app.GPS_satellite_display.Value;
            band = app.GPS_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GPS_band
        function GPS_bandValueChanged(app, event)
            satellite = app.GPS_satellite_display.Value;
            band = app.GPS_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GPS_arc
        function GPS_arcValueChanged(app, event)
            satellite = app.GPS_satellite_display.Value;
            band = app.GPS_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GLONASS_satellite_display
        function GLONASS_satellite_displayValueChanged(app, event)
            satellite= app.GLONASS_satellite_display.Value;
            band = app.GLONASS_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GLONASS_band
        function GLONASS_bandValueChanged(app, event)
            satellite= app.GLONASS_satellite_display.Value;
            band = app.GLONASS_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GLONASS_arc
        function GLONASS_arcValueChanged(app, event)
            satellite= app.GLONASS_satellite_display.Value;
            band = app.GLONASS_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GALILEO_satellite_display
        function GALILEO_satellite_displayValueChanged(app, event)
            satellite= app.GALILEO_satellite_display.Value;
            band = app.GALILEO_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GALILEO_band
        function GALILEO_bandValueChanged(app, event)
            satellite= app.GALILEO_satellite_display.Value;
            band = app.GALILEO_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GALILEO_arc
        function GALILEO_arcValueChanged(app, event)
            satellite= app.GALILEO_satellite_display.Value;
            band = app.GALILEO_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: BDS_band
        function BDS_bandValueChanged(app, event)
            satellite= app.BDS_satellite_display.Value;
            band = app.BDS_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: BDS_arc
        function BDS_arcValueChanged(app, event)
            satellite= app.BDS_satellite_display.Value;
            band = app.BDS_band.Value;
            snr_plot(app,satellite,band)
        end

        % Value changed function: GNSS_sky
        function GNSS_skyValueChanged(app, event)
            GNSSChangeSkyPlotCallback(app)
        end

        % Value changed function: satellite_sky
        function satellite_skyValueChanged(app, event)
            sat = app.satellite_sky.Value;
            system = string(app.GNSS_sky.Value);
            skyview_plot(app,system,sat)
        end

        % Button pushed function: load_station_info
        function load_station_infoButtonPushed(app, event)
            stationInfoCallback(app)
        end

        % Button pushed function: get_station_info
        function get_station_infoButtonPushed(app, event)
            GeoStationCallback(app)
        end

        % Button pushed function: path_o
        function path_oButtonPushed(app, event)
            ObsChooseCallback(app)
        end

        % Button pushed function: path_sp3
        function path_sp3ButtonPushed(app, event)
            EphChooseCallback(app)
        end

        % Button pushed function: getsp3
        function getsp3ButtonPushed(app, event)
            GetEphCallback(app)
        end

        % Button pushed function: SaveLoadSettingsButton
        function SaveLoadSettingsButtonPushed(app, event)
            SaveSettingCallback(app)
        end

        % Value changed function: window_choose
        function window_chooseValueChanged(app, event)
            value = app.window_choose.Value;
            if string(value) == "Off"
                app.win_gap.Enable = "off";
                app.win_length.Enable = "off";
            elseif string(value) == "On"
                app.win_gap.Enable = "on";
                app.win_length.Enable = "on";
            end
        end

        % Button pushed function: check_fen
        function check_fenButtonPushed(app, event)
            run fresnel_reflection_region.mlapp
        end

        % Value changed function: ImportSettingsButton
        function ImportSettingsButtonValueChanged(app, event)
            ImportSettingCallback(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Exit the program?',...
                'Confirm Close');
            switch selection
                case 'OK'
                    delete(app)
                case 'Cancel'
                    return
            end
        end

        % Button pushed function: chooseButton_4
        function chooseButton_4Pushed(app, event)
            dname = uigetdir([pwd,'/results/Final_file/'], 'Select the Final file path');
            app.FinalfilepathEditField.Value = dname;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Spectral';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icon', '地下水位.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup9 = uitabgroup(app.GridLayout);
            app.SettingsTab = uitab(app.TabGroup9);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout13 = uigridlayout(app.SettingsTab);
            app.TabGroup7 = uitabgroup(app.GridLayout13);
            app.StationTab = uitab(app.TabGroup7);
            app.StationTab.Title = 'Station';
            app.GridLayout14 = uigridlayout(app.StationTab);
            app.StationinformationPanel = uipanel(app.GridLayout14);
            app.StationinformationPanel.Title = 'Station information';
            app.GridLayout15 = uigridlayout(app.StationinformationPanel);
            app.LocationinfoPanel = uipanel(app.GridLayout15);
            app.LocationinfoPanel.Title = 'Location info';
            app.GridLayout16 = uigridlayout(app.LocationinfoPanel);
            app.XLabel = uilabel(app.GridLayout16);
            app.XLabel.Text = 'X';
            app.X = uieditfield(app.GridLayout16, 'numeric');
            app.X.ValueDisplayFormat = '%.4f';
            app.X.Value = -2168281.1141;
            app.mYLabel = uilabel(app.GridLayout16);
            app.mYLabel.Text = 'Y';
            app.Y = uieditfield(app.GridLayout16, 'numeric');
            app.Y.ValueDisplayFormat = '%.4f';
            app.Y.Value = 4387988.0809;
            app.mZLabel = uilabel(app.GridLayout16);
            app.mZLabel.Text = 'Z';
            app.Z = uieditfield(app.GridLayout16, 'numeric');
            app.Z.ValueDisplayFormat = '%.4f';
            app.Z.Value = 4076014.3417;
            app.LonLabel = uilabel(app.GridLayout16);
            app.LonLabel.Text = 'Lon';
            app.lon = uieditfield(app.GridLayout16, 'text');
            app.lon.Value = '116.2958266369';
            app.LatLabel = uilabel(app.GridLayout16);
            app.LatLabel.Text = 'Lat';
            app.lat = uieditfield(app.GridLayout16, 'text');
            app.lat.Value = '39.9761707123 ';
            app.get_station_info = uibutton(app.GridLayout16, 'push');
            app.get_station_info.ButtonPushedFcn = createCallbackFcn(app, @get_station_infoButtonPushed, true);
            app.get_station_info.Icon = fullfile(pathToMLAPP, 'icon', '出行导航.png');
            app.get_station_info.Text = 'Map the geographical location';
            app.GeodeticcoordinatesystemmLabel = uilabel(app.GridLayout16);
            app.GeodeticcoordinatesystemmLabel.Text = 'Geodetic coordinate system (m)';
            app.SpacerectangularcoordinatesystemmLabel = uilabel(app.GridLayout16);
            app.SpacerectangularcoordinatesystemmLabel.Text = 'Space rectangular coordinate system (m)';
            app.load_station_info = uibutton(app.GridLayout16, 'push');
            app.load_station_info.ButtonPushedFcn = createCallbackFcn(app, @load_station_infoButtonPushed, true);
            app.load_station_info.Icon = fullfile(pathToMLAPP, 'icon', '导入.png');
            app.load_station_info.Text = 'Import';
            app.HEditFieldLabel = uilabel(app.GridLayout16);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.GridLayout16, 'text');
            app.HEditField.Value = '87.1290197028';
            app.DropDown_2Label_5 = uilabel(app.GridLayout16);
            app.DropDown_2Label_5.Text = 'Base map type';
            app.basemap = uidropdown(app.GridLayout16);
            app.basemap.Items = {'satellite', 'topographic', 'streets-dark', 'streets'};
            app.basemap.Value = 'satellite';
            app.SettingsofelevationandazimuthPanel = uipanel(app.GridLayout15);
            app.SettingsofelevationandazimuthPanel.Title = ' Settings of elevation and azimuth';
            app.GridLayout17 = uigridlayout(app.SettingsofelevationandazimuthPanel);
            app.ElevationminLabel = uilabel(app.GridLayout17);
            app.ElevationminLabel.Text = 'Elevation: min(°)';
            app.elv_min = uieditfield(app.GridLayout17, 'text');
            app.elv_min.Value = '5';
            app.maxLabel = uilabel(app.GridLayout17);
            app.maxLabel.Text = 'max(°)';
            app.elv_max = uieditfield(app.GridLayout17, 'text');
            app.elv_max.Value = '30';
            app.AzimuthminLabel = uilabel(app.GridLayout17);
            app.AzimuthminLabel.Text = 'Azimuth: min(°)';
            app.azi_min = uieditfield(app.GridLayout17, 'text');
            app.azi_min.Value = '0';
            app.EditField_3Label_2 = uilabel(app.GridLayout17);
            app.EditField_3Label_2.Text = 'max(°)';
            app.azi_max = uieditfield(app.GridLayout17, 'text');
            app.azi_max.Value = '360';
            app.Label_28 = uilabel(app.GridLayout17);
            app.Label_28.Text = 'Azimuth mask(°)';
            app.azimask_min = uieditfield(app.GridLayout17, 'text');
            app.azimask_min.Value = '105';
            app.azimask_max = uieditfield(app.GridLayout17, 'text');
            app.azimask_max.Value = '300';
            app.EditField_3Label_3 = uilabel(app.GridLayout17);
            app.EditField_3Label_3.Text = 'to';
            app.Image8 = uiimage(app.GridLayout15);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'icon', 'satellite-tower.2.png');
            app.Label_21 = uilabel(app.GridLayout15);
            app.Label_21.Text = 'Name';
            app.station = uieditfield(app.GridLayout15, 'text');
            app.station.Value = 'at01';
            app.Label_22 = uilabel(app.GridLayout15);
            app.Label_22.Text = 'Belong';
            app.belong = uieditfield(app.GridLayout15, 'text');
            app.belong.Value = 'USA';
            app.ImportSettingsButton = uibutton(app.GridLayout15, 'state');
            app.ImportSettingsButton.ValueChangedFcn = createCallbackFcn(app, @ImportSettingsButtonValueChanged, true);
            app.ImportSettingsButton.Icon = fullfile(pathToMLAPP, 'icon', '导入.png');
            app.ImportSettingsButton.Text = 'Import Settings';
            app.stationmap = uipanel(app.GridLayout14);
            app.stationmap.Title = 'Geographical location diagram of the station';
            app.FilesTab = uitab(app.TabGroup7);
            app.FilesTab.Title = 'Files';
            app.GridLayout18 = uigridlayout(app.FilesTab);
            app.RINEXFilesPanel = uipanel(app.GridLayout18);
            app.RINEXFilesPanel.Title = 'RINEX Files';
            app.GridLayout20 = uigridlayout(app.RINEXFilesPanel);
            app.PathofrinexLabel = uilabel(app.GridLayout20);
            app.PathofrinexLabel.Text = 'Path of rinex: ';
            app.observation = uieditfield(app.GridLayout20, 'text');
            app.path_o = uibutton(app.GridLayout20, 'push');
            app.path_o.ButtonPushedFcn = createCallbackFcn(app, @path_oButtonPushed, true);
            app.path_o.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.path_o.Text = 'choose';
            app.obsfilenumber = uilabel(app.GridLayout20);
            app.obsfilenumber.Text = 'Selected: 0 files';
            app.Image2 = uiimage(app.GridLayout20);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星(1).png');
            app.rinexversionLabel = uilabel(app.GridLayout20);
            app.rinexversionLabel.Text = 'rinex version';
            app.rinex_version = uidropdown(app.GridLayout20);
            app.rinex_version.Items = {'rinex 2', 'rinex 3'};
            app.rinex_version.Value = 'rinex 3';
            app.Label_31 = uilabel(app.GridLayout20);
            app.Label_31.Text = 'RIENX Files List';
            app.obsfilename = uitextarea(app.GridLayout20);
            app.Label_32 = uilabel(app.GridLayout20);
            app.Label_32.Text = 'Interval';
            app.dt = uieditfield(app.GridLayout20, 'text');
            app.dt.Value = '30';
            app.EphemerisFilesPanel = uipanel(app.GridLayout18);
            app.EphemerisFilesPanel.Title = 'Ephemeris Files';
            app.GridLayout21 = uigridlayout(app.EphemerisFilesPanel);
            app.getsp3 = uibutton(app.GridLayout21, 'push');
            app.getsp3.ButtonPushedFcn = createCallbackFcn(app, @getsp3ButtonPushed, true);
            app.getsp3.Icon = fullfile(pathToMLAPP, 'icon', '文件下载.png');
            app.getsp3.Text = 'Get ephemeris files';
            app.Pathofsp3Label = uilabel(app.GridLayout21);
            app.Pathofsp3Label.Text = 'Path of sp3: ';
            app.sp3 = uieditfield(app.GridLayout21, 'text');
            app.path_sp3 = uibutton(app.GridLayout21, 'push');
            app.path_sp3.ButtonPushedFcn = createCallbackFcn(app, @path_sp3ButtonPushed, true);
            app.path_sp3.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.path_sp3.Text = 'choose';
            app.sp3filenumber = uilabel(app.GridLayout21);
            app.sp3filenumber.Text = 'Selected: 0 files';
            app.Image3 = uiimage(app.GridLayout21);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星定位连续运行站点.png');
            app.Label_36 = uilabel(app.GridLayout21);
            app.Label_36.Text = 'Analysis Center:';
            app.sp3_type = uidropdown(app.GridLayout21);
            app.sp3_type.Items = {'com', 'igs', 'COD', 'GFZ', 'WUM'};
            app.sp3_type.Value = 'GFZ';
            app.Label_34 = uilabel(app.GridLayout21);
            app.Label_34.Text = 'Ephemeris Files List';
            app.sp3filename = uitextarea(app.GridLayout21);
            app.TimsPanel = uipanel(app.GridLayout18);
            app.TimsPanel.Title = 'Tims';
            app.GridLayout19 = uigridlayout(app.TimsPanel);
            app.StartdateLabel = uilabel(app.GridLayout19);
            app.StartdateLabel.Text = 'Start date';
            app.starttime = uidatepicker(app.GridLayout19);
            app.starttime.Value = datetime([2023 4 10]);
            app.DatePicker_2Label = uilabel(app.GridLayout19);
            app.DatePicker_2Label.Text = 'End date';
            app.endtime = uidatepicker(app.GridLayout19);
            app.endtime.Value = datetime([2023 4 14]);
            app.Image = uiimage(app.GridLayout19);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon', '日期.png');
            app.Image_2 = uiimage(app.GridLayout19);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'icon', '日期.png');
            app.Image9 = uiimage(app.GridLayout18);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'icon', '全球(1).png');
            app.SaveLoadSettingsButton = uibutton(app.GridLayout18, 'push');
            app.SaveLoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLoadSettingsButtonPushed, true);
            app.SaveLoadSettingsButton.Icon = fullfile(pathToMLAPP, 'icon', '保存.png');
            app.SaveLoadSettingsButton.Text = 'Save & Load Settings';
            app.SNRextractionTab = uitab(app.TabGroup9);
            app.SNRextractionTab.Title = 'SNR extraction';
            app.GridLayout2 = uigridlayout(app.SNRextractionTab);
            app.ExtractSNRdataButton = uibutton(app.GridLayout2, 'push');
            app.ExtractSNRdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSNRdataButtonPushed, true);
            app.ExtractSNRdataButton.Icon = fullfile(pathToMLAPP, 'icon', '信号提取.png');
            app.ExtractSNRdataButton.Text = 'Extract SNR data';
            app.chooseButton = uibutton(app.GridLayout2, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.chooseButton.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton.Text = 'choose';
            app.ResultpathLabel = uilabel(app.GridLayout2);
            app.ResultpathLabel.Text = 'Result path:';
            app.path_SNR = uieditfield(app.GridLayout2, 'text');
            app.Label_11 = uilabel(app.GridLayout2);
            app.Label_11.Text = 'Date';
            app.display_date = uidropdown(app.GridLayout2);
            app.display_date.Items = {};
            app.display_date.ValueChangedFcn = createCallbackFcn(app, @display_dateValueChanged, true);
            app.display_date.Value = {};
            app.Image4 = uiimage(app.GridLayout2);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'icon', '时间.png');
            app.TabGroup8 = uitabgroup(app.GridLayout2);
            app.SNRTab_2 = uitab(app.TabGroup8);
            app.SNRTab_2.Title = 'SNR';
            app.GridLayout11 = uigridlayout(app.SNRTab_2);
            app.TabGroup2 = uitabgroup(app.GridLayout11);
            app.GPSTab = uitab(app.TabGroup2);
            app.GPSTab.Title = 'GPS';
            app.GridLayout4 = uigridlayout(app.GPSTab);
            app.GPS_SNR = uiaxes(app.GridLayout4);
            title(app.GPS_SNR, 'SNR')
            xlabel(app.GPS_SNR, 'sin(e)')
            ylabel(app.GPS_SNR, 'SNR(dB-Hz)')
            zlabel(app.GPS_SNR, 'Z')
            app.GPS_result = uitable(app.GridLayout4);
            app.GPS_result.ColumnName = '';
            app.GPS_result.RowName = {};
            app.ArcLabel = uilabel(app.GridLayout4);
            app.ArcLabel.Text = 'Arc';
            app.GPS_arc = uidropdown(app.GridLayout4);
            app.GPS_arc.Items = {'1'};
            app.GPS_arc.ValueChangedFcn = createCallbackFcn(app, @GPS_arcValueChanged, true);
            app.GPS_arc.Value = '1';
            app.Image5 = uiimage(app.GridLayout4);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'icon', '波段选择.png');
            app.Image6 = uiimage(app.GridLayout4);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星.png');
            app.Image7 = uiimage(app.GridLayout4);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'icon', '弧段信息.png');
            app.satelliteLabel = uilabel(app.GridLayout4);
            app.satelliteLabel.Text = 'Sat';
            app.GPS_satellite_display = uidropdown(app.GridLayout4);
            app.GPS_satellite_display.Items = {};
            app.GPS_satellite_display.ValueChangedFcn = createCallbackFcn(app, @GPS_satellite_displayValueChanged, true);
            app.GPS_satellite_display.Value = {};
            app.Label_12 = uilabel(app.GridLayout4);
            app.Label_12.Text = 'Band';
            app.GPS_band = uidropdown(app.GridLayout4);
            app.GPS_band.ValueChangedFcn = createCallbackFcn(app, @GPS_bandValueChanged, true);
            app.GLONASSTab = uitab(app.TabGroup2);
            app.GLONASSTab.Title = 'GLONASS';
            app.GridLayout5 = uigridlayout(app.GLONASSTab);
            app.GLONASS_SNR = uiaxes(app.GridLayout5);
            title(app.GLONASS_SNR, 'SNR')
            xlabel(app.GLONASS_SNR, 'sin(e)')
            ylabel(app.GLONASS_SNR, 'SNR(dB-Hz)')
            zlabel(app.GLONASS_SNR, 'Z')
            app.GLONASS_result = uitable(app.GridLayout5);
            app.GLONASS_result.ColumnName = '';
            app.GLONASS_result.RowName = {};
            app.Image6_2 = uiimage(app.GridLayout5);
            app.Image6_2.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星.png');
            app.Image5_2 = uiimage(app.GridLayout5);
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'icon', '波段选择.png');
            app.Image7_2 = uiimage(app.GridLayout5);
            app.Image7_2.ImageSource = fullfile(pathToMLAPP, 'icon', '弧段信息.png');
            app.DropDown_2Label_2 = uilabel(app.GridLayout5);
            app.DropDown_2Label_2.Text = 'Sat';
            app.GLONASS_satellite_display = uidropdown(app.GridLayout5);
            app.GLONASS_satellite_display.Items = {};
            app.GLONASS_satellite_display.ValueChangedFcn = createCallbackFcn(app, @GLONASS_satellite_displayValueChanged, true);
            app.GLONASS_satellite_display.Value = {};
            app.Label_14 = uilabel(app.GridLayout5);
            app.Label_14.Text = 'Band';
            app.GLONASS_band = uidropdown(app.GridLayout5);
            app.GLONASS_band.ValueChangedFcn = createCallbackFcn(app, @GLONASS_bandValueChanged, true);
            app.Label_15 = uilabel(app.GridLayout5);
            app.Label_15.Text = 'Arc';
            app.GLONASS_arc = uidropdown(app.GridLayout5);
            app.GLONASS_arc.Items = {'1'};
            app.GLONASS_arc.ValueChangedFcn = createCallbackFcn(app, @GLONASS_arcValueChanged, true);
            app.GLONASS_arc.Value = '1';
            app.GALILEOTab = uitab(app.TabGroup2);
            app.GALILEOTab.Title = 'GALILEO';
            app.GridLayout6 = uigridlayout(app.GALILEOTab);
            app.GALILEO_SNR = uiaxes(app.GridLayout6);
            title(app.GALILEO_SNR, 'SNR')
            xlabel(app.GALILEO_SNR, 'sin(e)')
            ylabel(app.GALILEO_SNR, 'SNR(dB-Hz)')
            zlabel(app.GALILEO_SNR, 'Z')
            app.GALILEO_result = uitable(app.GridLayout6);
            app.GALILEO_result.ColumnName = '';
            app.GALILEO_result.RowName = {};
            app.Image6_3 = uiimage(app.GridLayout6);
            app.Image6_3.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星.png');
            app.Image5_3 = uiimage(app.GridLayout6);
            app.Image5_3.ImageSource = fullfile(pathToMLAPP, 'icon', '波段选择.png');
            app.Image7_3 = uiimage(app.GridLayout6);
            app.Image7_3.ImageSource = fullfile(pathToMLAPP, 'icon', '弧段信息.png');
            app.DropDown_2Label_3 = uilabel(app.GridLayout6);
            app.DropDown_2Label_3.Text = 'Sat';
            app.GALILEO_satellite_display = uidropdown(app.GridLayout6);
            app.GALILEO_satellite_display.Items = {};
            app.GALILEO_satellite_display.ValueChangedFcn = createCallbackFcn(app, @GALILEO_satellite_displayValueChanged, true);
            app.GALILEO_satellite_display.Value = {};
            app.Label_16 = uilabel(app.GridLayout6);
            app.Label_16.Text = 'Band';
            app.GALILEO_band = uidropdown(app.GridLayout6);
            app.GALILEO_band.ValueChangedFcn = createCallbackFcn(app, @GALILEO_bandValueChanged, true);
            app.Label_17 = uilabel(app.GridLayout6);
            app.Label_17.Text = 'Arc';
            app.GALILEO_arc = uidropdown(app.GridLayout6);
            app.GALILEO_arc.Items = {'1'};
            app.GALILEO_arc.ValueChangedFcn = createCallbackFcn(app, @GALILEO_arcValueChanged, true);
            app.GALILEO_arc.Value = '1';
            app.BDSTab = uitab(app.TabGroup2);
            app.BDSTab.Title = 'BDS';
            app.GridLayout7 = uigridlayout(app.BDSTab);
            app.BDS_SNR = uiaxes(app.GridLayout7);
            title(app.BDS_SNR, 'SNR')
            xlabel(app.BDS_SNR, 'sin(e)')
            ylabel(app.BDS_SNR, 'SNR(dB-Hz)')
            zlabel(app.BDS_SNR, 'Z')
            app.BDS_result = uitable(app.GridLayout7);
            app.BDS_result.ColumnName = '';
            app.BDS_result.RowName = {};
            app.Image6_4 = uiimage(app.GridLayout7);
            app.Image6_4.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星.png');
            app.Image5_4 = uiimage(app.GridLayout7);
            app.Image5_4.ImageSource = fullfile(pathToMLAPP, 'icon', '波段选择.png');
            app.Image7_4 = uiimage(app.GridLayout7);
            app.Image7_4.ImageSource = fullfile(pathToMLAPP, 'icon', '弧段信息.png');
            app.DropDown_2Label_4 = uilabel(app.GridLayout7);
            app.DropDown_2Label_4.Text = 'Sat';
            app.BDS_satellite_display = uidropdown(app.GridLayout7);
            app.BDS_satellite_display.Items = {};
            app.BDS_satellite_display.ValueChangedFcn = createCallbackFcn(app, @BDS_satellite_displayValueChanged, true);
            app.BDS_satellite_display.Value = {};
            app.Label_18 = uilabel(app.GridLayout7);
            app.Label_18.Text = 'Band';
            app.BDS_band = uidropdown(app.GridLayout7);
            app.BDS_band.Items = {};
            app.BDS_band.ValueChangedFcn = createCallbackFcn(app, @BDS_bandValueChanged, true);
            app.BDS_band.Value = {};
            app.Label_19 = uilabel(app.GridLayout7);
            app.Label_19.Text = 'Arc';
            app.BDS_arc = uidropdown(app.GridLayout7);
            app.BDS_arc.Items = {'1'};
            app.BDS_arc.ValueChangedFcn = createCallbackFcn(app, @BDS_arcValueChanged, true);
            app.BDS_arc.Value = '1';
            app.SkymapTab = uitab(app.TabGroup8);
            app.SkymapTab.Title = 'Sky map';
            app.GridLayout12 = uigridlayout(app.SkymapTab);
            app.skyview = uitabgroup(app.GridLayout12);
            app.sky_all = uitab(app.skyview);
            app.sky_all.Title = 'All satellite arcs';
            app.sky_selected = uitab(app.skyview);
            app.sky_selected.Title = 'Active satellite arcs';
            app.GNSSLabel = uilabel(app.GridLayout12);
            app.GNSSLabel.Text = 'GNSS';
            app.GNSS_sky = uilistbox(app.GridLayout12);
            app.GNSS_sky.Items = {'GPS', 'GLONASS', 'GALILEO', 'BDS'};
            app.GNSS_sky.ValueChangedFcn = createCallbackFcn(app, @GNSS_skyValueChanged, true);
            app.GNSS_sky.Value = 'GPS';
            app.Label_20 = uilabel(app.GridLayout12);
            app.Label_20.Text = 'Satellite list';
            app.satellite_sky = uilistbox(app.GridLayout12);
            app.satellite_sky.Items = {};
            app.satellite_sky.ValueChangedFcn = createCallbackFcn(app, @satellite_skyValueChanged, true);
            app.satellite_sky.Value = {};
            app.WaterlevelinversionTab = uitab(app.TabGroup9);
            app.WaterlevelinversionTab.Title = 'Water level inversion';
            app.GridLayout8 = uigridlayout(app.WaterlevelinversionTab);
            app.UIAxes = uiaxes(app.GridLayout8);
            title(app.UIAxes, 'Results')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'RH(m)')
            zlabel(app.UIAxes, 'Z')
            app.ProcessBar = uiaxes(app.GridLayout8);
            app.ProcessBar.XTick = [];
            app.ProcessBar.XTickLabel = '';
            app.ProcessBar.YTick = [];
            app.analysis_result = uitable(app.GridLayout8);
            app.analysis_result.ColumnName = {'Time'; 'GNSS'; 'Band'; 'PRN'; 'ROC'; 'Elv_min'; 'Elv_max'; 'Avg_azi'; 'Reflect H'; 'mean mag. tSNR'; 'PSD peak'; 'variance'};
            app.analysis_result.RowName = {};
            app.EditField_2Label = uilabel(app.GridLayout8);
            app.EditField_2Label.Text = 'SNR Path: ';
            app.path_SNR_data = uieditfield(app.GridLayout8, 'text');
            app.chooseButton_2 = uibutton(app.GridLayout8, 'push');
            app.chooseButton_2.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_2Pushed, true);
            app.chooseButton_2.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_2.Text = 'choose';
            app.LSP_result = uipanel(app.GridLayout8);
            app.LSP_result.Title = 'Power spectral density';
            app.SNRPanel = uipanel(app.GridLayout8);
            app.SNRPanel.Title = 'SNR';
            app.analysis = uibutton(app.GridLayout8, 'push');
            app.analysis.ButtonPushedFcn = createCallbackFcn(app, @analysisButtonPushed, true);
            app.analysis.Icon = fullfile(pathToMLAPP, 'icon', '数据分析.png');
            app.analysis.Text = 'Inverse';
            app.chose_path_for_LSP = uibutton(app.GridLayout8, 'push');
            app.chose_path_for_LSP.ButtonPushedFcn = createCallbackFcn(app, @chose_path_for_LSPButtonPushed, true);
            app.chose_path_for_LSP.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chose_path_for_LSP.Text = 'choose';
            app.SavePathLabel = uilabel(app.GridLayout8);
            app.SavePathLabel.Text = 'Save Path:';
            app.analysis_result_path = uieditfield(app.GridLayout8, 'text');
            app.Panel = uipanel(app.GridLayout8);
            app.GridLayout9 = uigridlayout(app.Panel);
            app.Label_2 = uilabel(app.GridLayout9);
            app.Label_2.Text = 'Tropospheric model';
            app.trop = uidropdown(app.GridLayout9);
            app.trop.Items = {'None', 'Williams', 'Santamaria-Gomez'};
            app.trop.Value = 'Santamaria-Gomez';
            app.intervalsLabel = uilabel(app.GridLayout9);
            app.intervalsLabel.Text = 'interval(s)';
            app.decimates = uieditfield(app.GridLayout9, 'numeric');
            app.decimates.Value = 15;
            app.EditField_3Label = uilabel(app.GridLayout9);
            app.EditField_3Label.Text = 'Antenna height(m)';
            app.antenna_height = uieditfield(app.GridLayout9, 'numeric');
            app.antenna_height.Value = 12;
            app.RangemEditFieldLabel = uilabel(app.GridLayout9);
            app.RangemEditFieldLabel.Text = 'Range(m)';
            app.RangemEditField = uieditfield(app.GridLayout9, 'numeric');
            app.RangemEditField.Value = 1.5;
            app.Label = uilabel(app.GridLayout9);
            app.Label.Text = 'Plot:';
            app.ispic = uiswitch(app.GridLayout9, 'slider');
            app.ispic.Items = {'No', 'Yes'};
            app.ispic.Value = 'No';
            app.kmlLabel = uilabel(app.GridLayout9);
            app.kmlLabel.Text = 'Reflection area(.kml)';
            app.fresnel_kml = uiswitch(app.GridLayout9, 'slider');
            app.fresnel_kml.Items = {'No', 'Yes'};
            app.fresnel_kml.Value = 'No';
            app.check_fen = uibutton(app.GridLayout8, 'push');
            app.check_fen.ButtonPushedFcn = createCallbackFcn(app, @check_fenButtonPushed, true);
            app.check_fen.Icon = fullfile(pathToMLAPP, 'icon', '查看.png');
            app.check_fen.Text = 'View reflection area';
            app.RunningstatusLampLabel = uilabel(app.GridLayout8);
            app.RunningstatusLampLabel.Text = 'Running status';
            app.RunningstatusLamp = uilamp(app.GridLayout8);
            app.ProgressbarLabel = uilabel(app.GridLayout8);
            app.ProgressbarLabel.Text = 'Progress bar';
            app.SlidingwindowSettingsPanel = uipanel(app.GridLayout8);
            app.SlidingwindowSettingsPanel.Title = 'Sliding window Settings';
            app.GridLayout10 = uigridlayout(app.SlidingwindowSettingsPanel);
            app.Label_3 = uilabel(app.GridLayout10);
            app.Label_3.Text = 'Sliding window';
            app.window_choose = uiswitch(app.GridLayout10, 'slider');
            app.window_choose.ValueChangedFcn = createCallbackFcn(app, @window_chooseValueChanged, true);
            app.Label_4 = uilabel(app.GridLayout10);
            app.Label_4.Text = 'window length(°) :';
            app.win_length = uieditfield(app.GridLayout10, 'numeric');
            app.win_length.Enable = 'off';
            app.win_length.Value = 5;
            app.windowlengthLabel = uilabel(app.GridLayout10);
            app.windowlengthLabel.Text = 'window interval(°) :';
            app.win_gap = uieditfield(app.GridLayout10, 'numeric');
            app.win_gap.Enable = 'off';
            app.win_gap.Value = 2.5;
            app.TidallevelverificationTab = uitab(app.TabGroup9);
            app.TidallevelverificationTab.Title = 'Tidal level verification';
            app.GridLayout22 = uigridlayout(app.TidallevelverificationTab);
            app.results = uiaxes(app.GridLayout22);
            title(app.results, 'Inversion Results')
            xlabel(app.results, 'Time')
            ylabel(app.results, 'Water Level / m')
            zlabel(app.results, 'Z')
            app.chooseButton_3 = uibutton(app.GridLayout22, 'push');
            app.chooseButton_3.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_3Pushed, true);
            app.chooseButton_3.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_3.Text = 'choose';
            app.result_scatter = uibutton(app.GridLayout22, 'state');
            app.result_scatter.ValueChangedFcn = createCallbackFcn(app, @result_scatterValueChanged, true);
            app.result_scatter.Icon = fullfile(pathToMLAPP, 'icon', '绘制设置.png');
            app.result_scatter.Text = 'Plot ';
            app.Label_5 = uilabel(app.GridLayout22);
            app.Label_5.Text = 'Tidal Data: ';
            app.tide_path = uieditfield(app.GridLayout22, 'text');
            app.TidalcorrectionTab = uitab(app.TabGroup9);
            app.TidalcorrectionTab.Title = 'Tidal correction';
            app.GridLayout23 = uigridlayout(app.TidalcorrectionTab);
            app.ans_dongtai = uiaxes(app.GridLayout23);
            title(app.ans_dongtai, 'Results')
            xlabel(app.ans_dongtai, 'Time')
            ylabel(app.ans_dongtai, 'Water Level / m')
            zlabel(app.ans_dongtai, 'Z')
            app.tidal_constituent = uibuttongroup(app.GridLayout23);
            app.tidal_constituent.Title = 'Tidal component';
            app.O1K1N2M2S2 = uiradiobutton(app.tidal_constituent);
            app.O1K1N2M2S2.Text = 'Use subtides O1, K1, N2, M2, S2';
            app.O1K1N2M2S2.Value = true;
            app.all145tide = uiradiobutton(app.tidal_constituent);
            app.all145tide.Text = 'Use all 145 tidal components (long time series)';
            app.DrawingoptionsPanel = uipanel(app.GridLayout23);
            app.DrawingoptionsPanel.Title = 'Drawing options';
            app.GridLayout24 = uigridlayout(app.DrawingoptionsPanel);
            app.BA = uicheckbox(app.GridLayout24);
            app.BA.Text = 'Correrction contrast diagram';
            app.BA.Value = true;
            app.band_com = uicheckbox(app.GridLayout24);
            app.band_com.Text = 'Frequency contrast diagram';
            app.band_com.Value = true;
            app.SPD = uicheckbox(app.GridLayout24);
            app.SPD.Text = 'Fresnel reflection region';
            app.DH = uicheckbox(app.GridLayout24);
            app.DH.Text = 'Residual histogram';
            app.DH.Value = true;
            app.cor = uicheckbox(app.GridLayout24);
            app.cor.Text = 'Correlation scatter plot';
            app.cor.Value = true;
            app.Label_6 = uilabel(app.GridLayout23);
            app.Label_6.Text = 'Average daily inversion points:';
            app.point_day = uieditfield(app.GridLayout23, 'numeric');
            app.RMSEcmPanel = uipanel(app.GridLayout23);
            app.RMSEcmPanel.Title = 'RMSE(cm)';
            app.GridLayout25 = uigridlayout(app.RMSEcmPanel);
            app.Label_7 = uilabel(app.GridLayout25);
            app.Label_7.Text = 'Before ';
            app.before = uieditfield(app.GridLayout25, 'text');
            app.EditField_5Label_2 = uilabel(app.GridLayout25);
            app.EditField_5Label_2.Text = 'After';
            app.after = uieditfield(app.GridLayout25, 'text');
            app.CorrectPlotButton = uibutton(app.GridLayout23, 'state');
            app.CorrectPlotButton.ValueChangedFcn = createCallbackFcn(app, @CorrectPlotButtonValueChanged, true);
            app.CorrectPlotButton.Icon = fullfile(pathToMLAPP, 'icon', '沿街商户-dp-责令改正.png');
            app.CorrectPlotButton.Text = 'Correct & Plot';
            app.chooseButton_4 = uibutton(app.GridLayout23, 'push');
            app.chooseButton_4.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_4Pushed, true);
            app.chooseButton_4.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_4.Text = 'choose';
            app.FinalfilepathEditFieldLabel = uilabel(app.GridLayout23);
            app.FinalfilepathEditFieldLabel.Text = 'Final file path';
            app.FinalfilepathEditField = uieditfield(app.GridLayout23, 'text');
            app.SpectralanalysisinversionLabel = uilabel(app.GridLayout);
            app.SpectralanalysisinversionLabel.Text = 'Spectral analysis inversion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNR_spectral_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end