classdef Start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GMRWaterUIFigure             matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Tree                         matlab.ui.container.Tree
        InversionbasedonSNRNode      matlab.ui.container.TreeNode
        SpectralinversionNode        matlab.ui.container.TreeNode
        InversemodelingNode          matlab.ui.container.TreeNode
        InversionbasedonCarrierPseudorangeNode  matlab.ui.container.TreeNode
        CarrierCombinationNode       matlab.ui.container.TreeNode
        PseudorangeCombinationNode   matlab.ui.container.TreeNode
        CarrierPseudorangeCombinationNode  matlab.ui.container.TreeNode
        GMRWaterLabel                matlab.ui.control.Label
        AnalysisFinalFileButton      matlab.ui.control.Button
        IntroductionTextArea         matlab.ui.control.TextArea
        Label_4                      matlab.ui.control.Label
        BatchprocessingmoduleButton  matlab.ui.control.Button
        RunthismoduleButton          matlab.ui.control.StateButton
        SchematicDiagramLabel        matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ContextMenu                  matlab.ui.container.ContextMenu
        TurntothisMenu               matlab.ui.container.Menu
        ViewdetailsMenu              matlab.ui.container.Menu
        ContextMenu2                 matlab.ui.container.ContextMenu
        TurntothisMenu_2             matlab.ui.container.Menu
        ViewdetailsMenu_2            matlab.ui.container.Menu
        ContextMenu3                 matlab.ui.container.ContextMenu
        TurntothisMenu_3             matlab.ui.container.Menu
        ViewdetailsMenu_3            matlab.ui.container.Menu
        ContextMenu4                 matlab.ui.container.ContextMenu
        TurntothisMenu_4             matlab.ui.container.Menu
        ViewdetailsMenu_4            matlab.ui.container.Menu
        ContextMenu5                 matlab.ui.container.ContextMenu
        TurntothisMenu_5             matlab.ui.container.Menu
        ViewdetailsMenu_5            matlab.ui.container.Menu
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
        end

        % Callback function
        function station_setupValueChanged(app, event)
            run station_setup.mlapp
            return
        end

        % Callback function
        function Button_2ValueChanged(app, event)
            run Inversion_based_on_observed_information.mlapp
            return
        end

        % Callback function
        function SNRButtonValueChanged(app, event)
            run SNR_method_options.mlapp
            return
        end

        % Callback function
        function Button_3Pushed(app, event)
            run fresnel_reflection_region.mlapp
            return
        end

        % Callback function
        function SNR_based_methodValueChanged(app, event)
            
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end

        % Menu selected function: TurntothisMenu
        function TurntothisMenuSelected(app, event)
            global mode
            mode = 1;

            app.Image.ImageSource = strcat(pwd,"/vsdx/Spectral analysis.jpg");
            app.IntroductionTextArea.Value = "The SNR stagger sequence of effective altitude and azimuth angles " + ...
                "can be extracted from observation files, geographic information, and precise ephemeris data corresponding " + ...
                "to the time of the GNSS station. The main frequency of the residual sequence is determined through spectral " + ...
                "analysis, incorporating atmospheric refraction correction and sliding window correction simultaneously. " + ...
                "By combining this with the height of the measuring station, the water level data is obtained." + ...
                " A dynamic correction method is then applied to verify and evaluate the measured tide level data.";
            app.SchematicDiagramLabel.Text = 'Process preview';
            app.RunthismoduleButton.Visible = "on";
            app.RunthismoduleButton.Enable = "on";
        end

        % Context menu opening function: ContextMenu2
        function ContextMenu2Opening(app, event)
            
        end

        % Menu selected function: ViewdetailsMenu
        function ViewdetailsMenuSelected(app, event)

            open("GMR-Water_manual.pdf")
        end

        % Value changed function: RunthismoduleButton
        function RunthismoduleButtonValueChanged(app, event)
            global mode
            if mode == 1
                run SNR_spectral_analysis.mlapp
            elseif mode == 2
                run SNR_Inverse_model.mlapp
            elseif mode == 3
                run Inversion_based_on_carrier_information.mlapp
            elseif mode == 4
                run Inversion_based_on_pseudorange_information.mlapp
            elseif mode == 5
                run Inversion_based_on_carrier_and_pseudorange_information.mlapp
            end
        end

        % Menu selected function: TurntothisMenu_2
        function TurntothisMenu_2Selected(app, event)
            global mode
            mode = 2;

            app.Image.ImageSource = strcat(pwd,"/vsdx/Inverse modeling.jpg");
            app.IntroductionTextArea.Value = "The SNR residual sequence of effective height and azimuth angles can " + ...
                "be extracted from observation files, geographic information, and precise ephemeris data corresponding " + ...
                "to the time. Water level inversion results can be derived through spectral analysis. The control nodes " + ...
                "of a B-spline curve are used to construct the SNR residual sequence model, serving as the initial values " + ...
                "for least squares estimation in inverse modeling. A least squares solution is then performed, " + ...
                "incorporating the control nodes of the B-spline curve for the water level profile " + ...
                "into the solution parameters, resulting in the obtained inversion water level.";
            app.SchematicDiagramLabel.Text = 'Process preview';
            app.RunthismoduleButton.Visible = "on";
            app.RunthismoduleButton.Enable = "on";
        end

        % Close request function: GMRWaterUIFigure
        function GMRWaterUIFigureCloseRequest(app, event)
            
            selection = uiconfirm(app.GMRWaterUIFigure,'Exit the program?',...
                'Confirm Close');
            switch selection
                case 'OK'
                    delete(app)
                case 'Cancel'
                    return
            end

        end

        % Size changed function: GMRWaterUIFigure
        function GMRWaterUIFigureSizeChanged(app, event)
            position = app.GMRWaterUIFigure.Position;
            children = allchild(app.GridLayout);
            childrenWithFontSizeProperty = findall(children,'flat','-property','FontSize');

            if position(3) > 1600 && position(4) > 850
                set(childrenWithFontSizeProperty,'FontSize',24)
                app.GMRWaterLabel.FontSize = 42;
            else
                set(childrenWithFontSizeProperty,'FontSize',12)
                app.GMRWaterLabel.FontSize = 24;
            end
        end

        % Menu selected function: TurntothisMenu_3
        function TurntothisMenu_3Selected(app, event)
            global mode
            mode = 3;

            app.Image.ImageSource = strcat(pwd,"/vsdx/Carrier combination.jpg");
            app.IntroductionTextArea.Value = "Observations of the carrier phase for effective altitude and azimuth angles " + ...
                "can be extracted from the observation files, geographic information, and the precise ephemeris corresponding " + ...
                "to the GNSS station's time. By combining observations across different frequency bands, geometric distance, " + ...
                "tropospheric errors, and ionospheric errors (using tri-frequency data) can be mitigated. Frequency spectrum " + ...
                "analysis of the combined observation sequence is then performed to obtain the main frequency of the combined " + ...
                "signal. A relationship between frequency and water level is " + ...
                "established through simulation, leading to the determination of the inversion water level.";
            app.SchematicDiagramLabel.Text = 'Process preview';
            app.RunthismoduleButton.Visible = "on";
            app.RunthismoduleButton.Enable = "on";
        end

        % Menu selected function: TurntothisMenu_4
        function TurntothisMenu_4Selected(app, event)
            global mode
            mode = 4;

            app.Image.ImageSource = strcat(pwd,"/vsdx/Pseudorange combination.jpg");
            app.IntroductionTextArea.Value = "Pseudorange observations for effective altitude and " + ...
                "azimuth angles can be extracted from observation files, geographic information, and the " + ...
                "precise ephemeris corresponding to the time of the GNSS station. By combining observations" + ...
                " across different frequency bands, geometric distance, tropospheric errors, and ionospheric " + ...
                "errors (using tri-frequency data) can be mitigated. Spectral analysis of the combined observation " + ...
                "sequence is then performed to identify the main frequency of the combined signal. A relationship " + ...
                "between frequency " + ...
                "and water level is established through simulation, leading to the determination of the inversion water level.";
            app.SchematicDiagramLabel.Text = 'Process preview';
            app.RunthismoduleButton.Visible = "on";
            app.RunthismoduleButton.Enable = "on";
        end

        % Menu selected function: TurntothisMenu_5
        function TurntothisMenu_5Selected(app, event)
            global mode
            mode = 5;

            app.Image.ImageSource = strcat(pwd,"/vsdx/CP combination.jpg");
            app.IntroductionTextArea.Value = "Using the observation files, geographic information, and the " + ...
                "precise ephemeris corresponding to the time of the GNSS station, pseudo-distance observations " + ...
                "and carrier phase observations for effective altitude and azimuth angles can be extracted. By " + ...
                "combining these observations in a unified frequency band, geometric distance and tropospheric " + ...
                "errors can be mitigated, followed by spectral analysis of the combined observation sequence. This" + ...
                " process yields the main frequency of the combined signal, and a relationship between frequency and water " + ...
                "level is established through simulation to determine the inversion water level.";
            app.SchematicDiagramLabel.Text = 'Process preview';
            app.RunthismoduleButton.Visible = "on";
            app.RunthismoduleButton.Enable = "on";
        end

        % Button pushed function: BatchprocessingmoduleButton
        function BatchprocessingmoduleButtonPushed(app, event)
            run Batch_processing.mlapp
        end

        % Button pushed function: AnalysisFinalFileButton
        function AnalysisFinalFileButtonPushed(app, event)
            run Analysis_Final_File.mlapp
        end

        % Menu selected function: ViewdetailsMenu_2
        function ViewdetailsMenu_2Selected(app, event)
            open("GMR-Water_manual.pdf")
        end

        % Menu selected function: ViewdetailsMenu_3
        function ViewdetailsMenu_3Selected(app, event)
            open("GMR-Water_manual.pdf")
        end

        % Menu selected function: ViewdetailsMenu_4
        function ViewdetailsMenu_4Selected(app, event)
            open("GMR-Water_manual.pdf")
        end

        % Menu selected function: ViewdetailsMenu_5
        function ViewdetailsMenu_5Selected(app, event)
            open("GMR-Water_manual.pdf")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GMRWaterUIFigure = uifigure('Visible', 'off');
            app.GMRWaterUIFigure.Name = 'GMR-Water';
            app.GMRWaterUIFigure.Icon = fullfile(pathToMLAPP, 'icon', '全球(1).png');
            app.GMRWaterUIFigure.CloseRequestFcn = createCallbackFcn(app, @GMRWaterUIFigureCloseRequest, true);
            app.GMRWaterUIFigure.SizeChangedFcn = createCallbackFcn(app, @GMRWaterUIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.GMRWaterUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'vsdx', 'Schematic drawing.jpg');
            app.SchematicDiagramLabel = uilabel(app.GridLayout);
            app.SchematicDiagramLabel.Text = 'Schematic Diagram';
            app.RunthismoduleButton = uibutton(app.GridLayout, 'state');
            app.RunthismoduleButton.ValueChangedFcn = createCallbackFcn(app, @RunthismoduleButtonValueChanged, true);
            app.RunthismoduleButton.Enable = 'off';
            app.RunthismoduleButton.Visible = 'off';
            app.RunthismoduleButton.Icon = fullfile(pathToMLAPP, 'icon', '运行.png');
            app.RunthismoduleButton.Text = 'Run this module';
            app.BatchprocessingmoduleButton = uibutton(app.GridLayout, 'push');
            app.BatchprocessingmoduleButton.ButtonPushedFcn = createCallbackFcn(app, @BatchprocessingmoduleButtonPushed, true);
            app.BatchprocessingmoduleButton.Icon = fullfile(pathToMLAPP, 'icon', 'Batch.png');
            app.BatchprocessingmoduleButton.Text = 'Batch processing module';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = 'Introduction';
            app.IntroductionTextArea = uitextarea(app.GridLayout);
            app.IntroductionTextArea.Editable = 'off';
            app.IntroductionTextArea.Value = {'By setting up a GNSS receiver on the seashore and utilizing the SNR information, carrier, or pseudo-range data from the observation file, the height of the surrounding sea can be accurately determined. This approach incorporates the geographical information of the observation station, the observation file, and the precise ephemeris file for the corresponding time. Based on GNSS-MR technology, the software employs various methods to calculate the height of the surrounding water surface and integrates tide observation data to verify the accuracy of the inversion.'};
            app.AnalysisFinalFileButton = uibutton(app.GridLayout, 'push');
            app.AnalysisFinalFileButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisFinalFileButtonPushed, true);
            app.AnalysisFinalFileButton.Icon = fullfile(pathToMLAPP, 'icon', 'Analysis.png');
            app.AnalysisFinalFileButton.Text = 'Analysis Final File';
            app.GMRWaterLabel = uilabel(app.GridLayout);
            app.GMRWaterLabel.Text = '    GMR-Water';
            app.Tree = uitree(app.GridLayout);
            app.InversionbasedonSNRNode = uitreenode(app.Tree);
            app.InversionbasedonSNRNode.Text = 'Inversion based on SNR';
            app.SpectralinversionNode = uitreenode(app.InversionbasedonSNRNode);
            app.SpectralinversionNode.Icon = fullfile(pathToMLAPP, 'icon', '波段选择.png');
            app.SpectralinversionNode.Text = 'Spectral inversion';
            app.InversemodelingNode = uitreenode(app.InversionbasedonSNRNode);
            app.InversemodelingNode.Icon = fullfile(pathToMLAPP, 'icon', '数据建模.png');
            app.InversemodelingNode.Text = 'Inverse modeling';
            app.InversionbasedonCarrierPseudorangeNode = uitreenode(app.Tree);
            app.InversionbasedonCarrierPseudorangeNode.Text = 'Inversion based on Carrier/Pseudorange';
            app.CarrierCombinationNode = uitreenode(app.InversionbasedonCarrierPseudorangeNode);
            app.CarrierCombinationNode.Icon = fullfile(pathToMLAPP, 'icon', 'list_组合.png');
            app.CarrierCombinationNode.Text = 'Carrier Combination';
            app.PseudorangeCombinationNode = uitreenode(app.InversionbasedonCarrierPseudorangeNode);
            app.PseudorangeCombinationNode.Icon = fullfile(pathToMLAPP, 'icon', '自选组合(1).png');
            app.PseudorangeCombinationNode.Text = 'Pseudo range Combination';
            app.CarrierPseudorangeCombinationNode = uitreenode(app.InversionbasedonCarrierPseudorangeNode);
            app.CarrierPseudorangeCombinationNode.Icon = fullfile(pathToMLAPP, 'icon', 'combination.png');
            app.CarrierPseudorangeCombinationNode.Text = 'Carrier&Pseudo range Combination';
            app.ContextMenu = uicontextmenu(app.GMRWaterUIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.TurntothisMenu = uimenu(app.ContextMenu);
            app.TurntothisMenu.MenuSelectedFcn = createCallbackFcn(app, @TurntothisMenuSelected, true);
            app.TurntothisMenu.Text = 'Turn to this';
            app.ViewdetailsMenu = uimenu(app.ContextMenu);
            app.ViewdetailsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewdetailsMenuSelected, true);
            app.ViewdetailsMenu.Text = 'View details';
            % Assign app.ContextMenu
            app.SpectralinversionNode.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.GMRWaterUIFigure);
            app.ContextMenu2.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu2Opening, true);
            app.TurntothisMenu_2 = uimenu(app.ContextMenu2);
            app.TurntothisMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TurntothisMenu_2Selected, true);
            app.TurntothisMenu_2.Text = 'Turn to this';
            app.ViewdetailsMenu_2 = uimenu(app.ContextMenu2);
            app.ViewdetailsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ViewdetailsMenu_2Selected, true);
            app.ViewdetailsMenu_2.Text = 'View details';
            % Assign app.ContextMenu2
            app.InversemodelingNode.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.GMRWaterUIFigure);
            app.TurntothisMenu_3 = uimenu(app.ContextMenu3);
            app.TurntothisMenu_3.MenuSelectedFcn = createCallbackFcn(app, @TurntothisMenu_3Selected, true);
            app.TurntothisMenu_3.Text = 'Turn to this';
            app.ViewdetailsMenu_3 = uimenu(app.ContextMenu3);
            app.ViewdetailsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ViewdetailsMenu_3Selected, true);
            app.ViewdetailsMenu_3.Text = 'View details';
            % Assign app.ContextMenu3
            app.CarrierCombinationNode.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.GMRWaterUIFigure);
            app.TurntothisMenu_4 = uimenu(app.ContextMenu4);
            app.TurntothisMenu_4.MenuSelectedFcn = createCallbackFcn(app, @TurntothisMenu_4Selected, true);
            app.TurntothisMenu_4.Text = 'Turn to this';
            app.ViewdetailsMenu_4 = uimenu(app.ContextMenu4);
            app.ViewdetailsMenu_4.MenuSelectedFcn = createCallbackFcn(app, @ViewdetailsMenu_4Selected, true);
            app.ViewdetailsMenu_4.Text = 'View details';
            % Assign app.ContextMenu4
            app.PseudorangeCombinationNode.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.GMRWaterUIFigure);
            app.TurntothisMenu_5 = uimenu(app.ContextMenu5);
            app.TurntothisMenu_5.MenuSelectedFcn = createCallbackFcn(app, @TurntothisMenu_5Selected, true);
            app.TurntothisMenu_5.Text = 'Turn to this';
            app.ViewdetailsMenu_5 = uimenu(app.ContextMenu5);
            app.ViewdetailsMenu_5.MenuSelectedFcn = createCallbackFcn(app, @ViewdetailsMenu_5Selected, true);
            app.ViewdetailsMenu_5.Text = 'View details';
            % Assign app.ContextMenu5
            app.CarrierPseudorangeCombinationNode.ContextMenu = app.ContextMenu5;
            % Show the figure after all components are created
            app.GMRWaterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GMRWaterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GMRWaterUIFigure)
        end
    end
end